classdef LOOPER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TriallambdaEditField            matlab.ui.control.NumericEditField
        TriallambdaEditFieldLabel       matlab.ui.control.Label
        MergetrialendsLabel             matlab.ui.control.Label
        MergeEndCheckbox                matlab.ui.control.CheckBox
        SlowLabel                       matlab.ui.control.Label
        LocaldimensionsLabel            matlab.ui.control.Label
        ChaaruCheckbox                  matlab.ui.control.CheckBox
        TimeEditField                   matlab.ui.control.EditField
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeSearch                      matlab.ui.control.Button
        YoumayoptionallyspecifyoutputsLabel_2  matlab.ui.control.Label
        ValidatePanel                   matlab.ui.container.Panel
        ClearvalidationdataButton       matlab.ui.control.Button
        LoadvalidationdataButton        matlab.ui.control.Button
        BeststatecountEditField         matlab.ui.control.EditField
        BeststatecountEditFieldLabel    matlab.ui.control.Label
        BestloopcountEditField          matlab.ui.control.EditField
        BestloopcountEditFieldLabel     matlab.ui.control.Label
        ViewspectraButton               matlab.ui.control.Button
        ValidateButton_2                matlab.ui.control.Button
        ClearcomparisonmodelButton      matlab.ui.control.Button
        LoadcomparisonmodelButton       matlab.ui.control.Button
        LoadMarkovPanel                 matlab.ui.container.Panel
        STATESEditField                 matlab.ui.control.NumericEditField
        STATESEditFieldLabel            matlab.ui.control.Label
        CancelButton_2                  matlab.ui.control.Button
        EmissionsSearch                 matlab.ui.control.Button
        TransitionmatrixSearch          matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        EmissionsEditField              matlab.ui.control.EditField
        EmissionsEditFieldLabel         matlab.ui.control.Label
        YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel_2  matlab.ui.control.Label
        TransitionmatrixEditField       matlab.ui.control.EditField
        TransitionmatrixEditFieldLabel  matlab.ui.control.Label
        LoaddataintoworkspaceandenterthenameofthevariableLabel_2  matlab.ui.control.Label
        FindloopsPanel                  matlab.ui.container.Panel
        UseterminalstateLabel           matlab.ui.control.Label
        TerminalStateCheckbox           matlab.ui.control.CheckBox
        StartingloopsEditField          matlab.ui.control.NumericEditField
        StartingloopsEditFieldLabel     matlab.ui.control.Label
        SelectloopsButton               matlab.ui.control.Button
        TargetstatecountEditField       matlab.ui.control.NumericEditField
        TargetstatecountEditFieldLabel  matlab.ui.control.Label
        PutativeloopcountsEditField     matlab.ui.control.EditField
        PutativeloopcountsEditFieldLabel  matlab.ui.control.Label
        SeparatewithcommasLabel_2       matlab.ui.control.Label
        ProcessloopsButton              matlab.ui.control.Button
        ModelreductionPanel             matlab.ui.container.Panel
        MaxchecktimeEditField           matlab.ui.control.NumericEditField
        MaxchecktimeEditFieldLabel      matlab.ui.control.Label
        DistancetypeEditField           matlab.ui.control.EditField
        DistancemeasureEditFieldLabel   matlab.ui.control.Label
        SeparatewithcommasLabel         matlab.ui.control.Label
        PutativeclustercountsEditField  matlab.ui.control.EditField
        PutativeclustercountsEditFieldLabel  matlab.ui.control.Label
        ReducemodelButton               matlab.ui.control.Button
        DiffusionmappingPanel           matlab.ui.container.Panel
        MinReturnTimeEditField          matlab.ui.control.NumericEditField
        MinReturnTimeEditFieldLabel     matlab.ui.control.Label
        RepopulationdensityEditField    matlab.ui.control.NumericEditField
        RepopulationdensityEditFieldLabel  matlab.ui.control.Label
        UselocaldimensionsLabel         matlab.ui.control.Label
        LocalDimensionsCheckbox         matlab.ui.control.CheckBox
        NearestneighborsEditField       matlab.ui.control.NumericEditField
        NearestneighborsEditFieldLabel  matlab.ui.control.Label
        PreviewindexEditField           matlab.ui.control.NumericEditField
        PreviewindexEditFieldLabel      matlab.ui.control.Label
        PreviewneighorhoodButton        matlab.ui.control.Button
        BuilddiffusionmapButton         matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        MergetrialstartLabel            matlab.ui.control.Label
        MergeStartCheckbox              matlab.ui.control.CheckBox
        OutputlambdaEditField           matlab.ui.control.NumericEditField
        OutputlambdaEditFieldLabel      matlab.ui.control.Label
        InputlambdaEditField            matlab.ui.control.NumericEditField
        InputlambdaEditFieldLabel       matlab.ui.control.Label
        EmbedcountEditField             matlab.ui.control.NumericEditField
        EmbedcountEditFieldLabel        matlab.ui.control.Label
        EmbeddelayEditField             matlab.ui.control.NumericEditField
        EmbeddelayEditFieldLabel        matlab.ui.control.Label
        ZScoreLabel                     matlab.ui.control.Label
        SmoothingEditField              matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel         matlab.ui.control.Label
        ZScoreCheckbox                  matlab.ui.control.CheckBox
        ApplyprocessingButton           matlab.ui.control.Button
        SelectDataPanel                 matlab.ui.container.Panel
        CancelButton                    matlab.ui.control.Button
        SelectvariableButton            matlab.ui.control.Button
        SelectDataList                  matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        FigureTabGroup                  matlab.ui.container.TabGroup
        FigureTab1                      matlab.ui.container.Tab
        Figure1                         matlab.ui.control.UIAxes
        LoadDataPanel                   matlab.ui.container.Panel
        Cancel                          matlab.ui.control.Button
        OutputsSearch                   matlab.ui.control.Button
        InputsSearch                    matlab.ui.control.Button
        DataSearch                      matlab.ui.control.Button
        LoadworkspacedataButton         matlab.ui.control.Button
        OutputsEditField                matlab.ui.control.EditField
        OutputsEditFieldLabel           matlab.ui.control.Label
        YoumayoptionallyspecifyoutputsLabel  matlab.ui.control.Label
        InputsEditField                 matlab.ui.control.EditField
        InputsEditFieldLabel            matlab.ui.control.Label
        YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel  matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        DataEditFieldLabel              matlab.ui.control.Label
        LoaddataintoworkspaceandenterthenameofthevariableLabel  matlab.ui.control.Label
        MainPanel                       matlab.ui.container.Panel
        SaveToWorkspaceButton           matlab.ui.control.Button
        RunclusteringButton             matlab.ui.control.Button
        RunallButton                    matlab.ui.control.Button
        PlottimesEditField              matlab.ui.control.EditField
        PlottimesEditFieldLabel         matlab.ui.control.Label
        LoadmatfileButton               matlab.ui.control.Button
        ExperimentNameEditField         matlab.ui.control.EditField
        ExperimentNameLabel             matlab.ui.control.Label
        SavedataButton                  matlab.ui.control.Button
        SelectstepButtonGroup           matlab.ui.container.ButtonGroup
        ValidateButton                  matlab.ui.control.RadioButton
        FindLoopsButton                 matlab.ui.control.RadioButton
        ModelReductionButton            matlab.ui.control.RadioButton
        DiffusionMapButton              matlab.ui.control.RadioButton
        PreprocessingButton             matlab.ui.control.RadioButton
        LoadButton                      matlab.ui.control.RadioButton
    end

    properties (Constant, Access = private)
        AppBaseDir = fileparts(fileparts(mfilename('fullpath')));
    end

    properties (Access = private)
        ComponentList % List of components for searching
        ComponentNames % List of components for searching
        
        SelectVariableBox
        SelectVariableCaller
        
        CurrentState
        
        SavedData (1,1) struct
        
        MainWindow
        
        FigureTabs
        Figures
        FigureIDs
        
        LoadingAlternative
        
        DefaultFilename = 'exp';
        DefaultFileLocation = fullfile(LOOPER.AppBaseDir, 'backup.mat');
    end

    methods (Access = private)    
        %% In app plotting
        setupTabs(app)
        tabID = newTab(app, figureNumber)
        removeFigure(app, figure)
    
        %% app functions    
        setupState(app)
        dataExists = hasData(app, dataName)
        removeData(app, dataName)
        [filename, path] = getDefaultExperimentName(app)           
        saveLoadedData(app, filename)
        checkStepValidity(app)
        showLoadPanel(app, loadingAlternative)
        initSettings(app)
        setUpUI(app)
        loadPreprocessing(app, shouldPlot)
        plotProcessedData(app)
        [success, rawData, inputs, outputs, trialData] = processMultipleTrials(app, affix)
        [pcaBasis, thisPlot, trialIndices] = plotPCA(app, shouldPlot, data, trialEnds)
        plotLoadedData(app)
        plotDiffusionMap(app)
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app, varargin)
            cb_startup(app, varargin{:});
        end

        % Button pushed function: DataSearch, EmissionsSearch, 
        % ...and 4 other components
        function SearchWorkspace(app, event)
            cb_SearchWorkspace(app, event);
        end

        % Button pushed function: SelectvariableButton
        function SelectVariable(app, event)
            cb_SelectVariable(app, event);
        end

        % Selection changed function: SelectstepButtonGroup
        function StepSelected(app, event)
            cb_StepSelected(app, event);
        end

        % Value changed function: SelectDataList
        function ClickData(app, event)
            cb_ClickData(app, event);
        end

        % Button pushed function: LoadworkspacedataButton
        function LoadData(app, event)
            cb_LoadData(app, event);
        end

        % Button pushed function: ApplyprocessingButton
        function ApplyPreprocessing(app, event)
           cb_ApplyPreprocessing(app, event);
        end

        % Button pushed function: LoadmatfileButton
        function LoadFile(app, event)
            cb_LoadFile(app, event);
        end

        % Button pushed function: CancelButton
        function SelectCancel(app, event)
            cb_SelectCancel(app, event);
        end

        % Button pushed function: SavedataButton
        function SaveFile(app, event)
            cb_SaveFile(app, event);
        end

        % Button pushed function: PreviewneighorhoodButton
        function PreviewNeighborhood(app, event)
            cb_PreviewNeighborhood(app, event);
        end

        % Button pushed function: BuilddiffusionmapButton
        function BuildDiffusionMap(app, event)
            cb_BuildDiffusionMap(app, event);
        end

        % Button pushed function: ReducemodelButton
        function ReduceModel(app, event)
            cb_ReduceModel(app, event);
        end

        % Button pushed function: ProcessloopsButton
        function FindLoops(app, event)
            cb_FindLoops(app, event);
        end

        % Button pushed function: LoadcomparisonmodelButton
        function LoadModel(app, event)
            cb_LoadModel(app, event);
        end

        % Button pushed function: LoadButton_2
        function LoadModelData(app, event)
            cb_LoadModelData(app, event);
        end

        % Button pushed function: CancelButton_2
        function CancelLoadModel(app, event)
            cb_CancelLoadModel(app, event);
        end

        % Button pushed function: ClearcomparisonmodelButton
        function ClearModel(app, event)
            cb_ClearModel(app, event);
        end

        % Button pushed function: ValidateButton_2
        function Validate(app, event)
            cb_Validate(app, event);
        end

        % Button pushed function: ClearvalidationdataButton
        function ClearValidationData(app, event)
            cb_ClearValidationData(app, event);
        end

        % Button pushed function: LoadvalidationdataButton
        function LoadValidationData(app, event)
            cb_LoadValidationData(app, event);
        end

        % Button pushed function: Cancel
        function CancelLoadData(app, event)
            cb_CancelLoadData(app, event);
        end

        % Button pushed function: SelectloopsButton
        function SelectLoops(app, event)
            cb_SelectLoops(app, event);
        end

        % Button pushed function: RunallButton
        function RunAllSteps(app, event)
            cb_RunAllSteps(app, event);
        end

        % Button pushed function: RunclusteringButton
        function RunClusteringSteps(app, event)
            cb_RunClusteringSteps(app, event);
        end

        % Button pushed function: SaveToWorkspaceButton
        function SaveToWorkSpace(app, event)
            cb_SaveToWorkspace(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.SelectstepButtonGroup = uibuttongroup(app.MainPanel);
            app.SelectstepButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StepSelected, true);
            app.SelectstepButtonGroup.Title = 'Select step';
            app.LoadButton = uiradiobutton(app.SelectstepButtonGroup);
            app.LoadButton.Text = 'Load data';
            app.LoadButton.Value = true;
            app.PreprocessingButton = uiradiobutton(app.SelectstepButtonGroup);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.DiffusionMapButton = uiradiobutton(app.SelectstepButtonGroup);
            app.DiffusionMapButton.Text = 'Diffusion map';
            app.ModelReductionButton = uiradiobutton(app.SelectstepButtonGroup);
            app.ModelReductionButton.Text = 'Model reduction';
            app.FindLoopsButton = uiradiobutton(app.SelectstepButtonGroup);
            app.FindLoopsButton.Text = 'Find loops';
            app.ValidateButton = uiradiobutton(app.SelectstepButtonGroup);
            app.ValidateButton.Enable = 'off';
            app.ValidateButton.Text = 'Validate';
            app.SavedataButton = uibutton(app.MainPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFile, true);
            app.SavedataButton.Text = 'Save data';
            app.ExperimentNameLabel = uilabel(app.MainPanel);
            app.ExperimentNameLabel.Text = 'Experiment Name:';
            app.ExperimentNameEditField = uieditfield(app.MainPanel, 'text');
            app.LoadmatfileButton = uibutton(app.MainPanel, 'push');
            app.LoadmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadmatfileButton.Text = 'Load matfile';
            app.PlottimesEditFieldLabel = uilabel(app.MainPanel);
            app.PlottimesEditFieldLabel.Text = 'Plot times';
            app.PlottimesEditField = uieditfield(app.MainPanel, 'text');
            app.RunallButton = uibutton(app.MainPanel, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllSteps, true);
            app.RunallButton.Text = 'Run all';
            app.RunclusteringButton = uibutton(app.MainPanel, 'push');
            app.RunclusteringButton.ButtonPushedFcn = createCallbackFcn(app, @RunClusteringSteps, true);
            app.RunclusteringButton.Text = 'Run clustering';
            app.SaveToWorkspaceButton = uibutton(app.MainPanel, 'push');
            app.SaveToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToWorkSpace, true);
            app.SaveToWorkspaceButton.Text = 'Save To Workspace';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load data';
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel = uilabel(app.LoadDataPanel);
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel.Text = {'Load time series data into the workspace. Enter name of '; 'the variable to load, or click the search button to browse.'; 'For multiple trials use cell array of CHANNEL x TICKS, or '; 'CHANNEL x TICK x TRIAL'};
            app.DataEditFieldLabel = uilabel(app.LoadDataPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.LoadDataPanel, 'text');
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel = uilabel(app.LoadDataPanel);
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel.Text = 'INPUTS x TIME (Optional)';
            app.InputsEditFieldLabel = uilabel(app.LoadDataPanel);
            app.InputsEditFieldLabel.Text = 'Inputs';
            app.InputsEditField = uieditfield(app.LoadDataPanel, 'text');
            app.YoumayoptionallyspecifyoutputsLabel = uilabel(app.LoadDataPanel);
            app.YoumayoptionallyspecifyoutputsLabel.Text = 'OUTPUTS x TIME (Optional)';
            app.OutputsEditFieldLabel = uilabel(app.LoadDataPanel);
            app.OutputsEditFieldLabel.Text = 'Outputs';
            app.OutputsEditField = uieditfield(app.LoadDataPanel, 'text');
            app.LoadworkspacedataButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadworkspacedataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadworkspacedataButton.Text = 'Load workspace data';
            app.DataSearch = uibutton(app.LoadDataPanel, 'push');
            app.DataSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.DataSearch.Icon = 'MagGLass.png';
            app.DataSearch.Text = '';
            app.InputsSearch = uibutton(app.LoadDataPanel, 'push');
            app.InputsSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.InputsSearch.Icon = 'MagGLass.png';
            app.InputsSearch.Text = '';
            app.OutputsSearch = uibutton(app.LoadDataPanel, 'push');
            app.OutputsSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.OutputsSearch.Icon = 'MagGLass.png';
            app.OutputsSearch.Text = '';
            app.Cancel = uibutton(app.LoadDataPanel, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelLoadData, true);
            app.Cancel.Text = 'Cancel';
            app.FigureTabGroup = uitabgroup(app.UIFigure);
            app.FigureTab1 = uitab(app.FigureTabGroup);
            app.FigureTab1.Title = 'Figure 1';
            app.Figure1 = uiaxes(app.FigureTab1);
            xlabel(app.Figure1, 'X')
            ylabel(app.Figure1, 'Y')
            app.SelectDataPanel = uipanel(app.UIFigure);
            app.SelectDataPanel.Title = 'Select data';
            app.Label = uilabel(app.SelectDataPanel);
            app.Label.Text = '';
            app.SelectDataList = uilistbox(app.SelectDataPanel);
            app.SelectDataList.Items = {'Test'};
            app.SelectDataList.ValueChangedFcn = createCallbackFcn(app, @ClickData, true);
            app.SelectDataList.Value = 'Test';
            app.SelectvariableButton = uibutton(app.SelectDataPanel, 'push');
            app.SelectvariableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVariable, true);
            app.SelectvariableButton.Text = 'Select variable';
            app.CancelButton = uibutton(app.SelectDataPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCancel, true);
            app.CancelButton.Text = 'Cancel';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.ApplyprocessingButton = uibutton(app.PreprocessingPanel, 'push');
            app.ApplyprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPreprocessing, true);
            app.ApplyprocessingButton.Text = 'Apply processing';
            app.ZScoreCheckbox = uicheckbox(app.PreprocessingPanel);
            app.ZScoreCheckbox.Text = '';
            app.SmoothingEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.SmoothingEditField.Limits = [0 50];
            app.ZScoreLabel = uilabel(app.PreprocessingPanel);
            app.ZScoreLabel.Text = 'Z-Score';
            app.EmbeddelayEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.EmbeddelayEditFieldLabel.Text = 'Embed delay';
            app.EmbeddelayEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.EmbeddelayEditField.Limits = [0 10000];
            app.EmbedcountEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.EmbedcountEditFieldLabel.Text = 'Embed count';
            app.EmbedcountEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.EmbedcountEditField.Limits = [0 10000];
            app.InputlambdaEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.InputlambdaEditFieldLabel.Text = 'Input lambda';
            app.InputlambdaEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.InputlambdaEditField.Limits = [0 10];
            app.InputlambdaEditField.Value = 1;
            app.OutputlambdaEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.OutputlambdaEditFieldLabel.Text = 'Output lambda';
            app.OutputlambdaEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.OutputlambdaEditField.Limits = [0 10];
            app.OutputlambdaEditField.Value = 1;
            app.MergeStartCheckbox = uicheckbox(app.PreprocessingPanel);
            app.MergeStartCheckbox.Text = '';
            app.MergetrialstartLabel = uilabel(app.PreprocessingPanel);
            app.MergetrialstartLabel.Text = 'Merge trial start';
            app.DiffusionmappingPanel = uipanel(app.UIFigure);
            app.DiffusionmappingPanel.Title = 'Diffusion mapping';
            app.BuilddiffusionmapButton = uibutton(app.DiffusionmappingPanel, 'push');
            app.BuilddiffusionmapButton.ButtonPushedFcn = createCallbackFcn(app, @BuildDiffusionMap, true);
            app.BuilddiffusionmapButton.Text = 'Build diffusion map';
            app.PreviewneighorhoodButton = uibutton(app.DiffusionmappingPanel, 'push');
            app.PreviewneighorhoodButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewNeighborhood, true);
            app.PreviewneighorhoodButton.Text = 'Preview neighorhood';
            app.PreviewindexEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.PreviewindexEditFieldLabel.Text = 'Preview index';
            app.PreviewindexEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.PreviewindexEditField.Limits = [1 Inf];
            app.PreviewindexEditField.Value = 1;
            app.NearestneighborsEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.NearestneighborsEditFieldLabel.Text = 'Nearest neighbors';
            app.NearestneighborsEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.NearestneighborsEditField.Limits = [2 Inf];
            app.NearestneighborsEditField.Value = 6;
            app.LocalDimensionsCheckbox = uicheckbox(app.DiffusionmappingPanel);
            app.LocalDimensionsCheckbox.Text = '';
            app.UselocaldimensionsLabel = uilabel(app.DiffusionmappingPanel);
            app.UselocaldimensionsLabel.Text = 'Use local dimensions';
            app.RepopulationdensityEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.RepopulationdensityEditFieldLabel.Text = 'Repopulation density';
            app.RepopulationdensityEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.RepopulationdensityEditField.Limits = [0 1];
            app.RepopulationdensityEditField.Value = 0.95;
            app.MinReturnTimeEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.MinReturnTimeEditFieldLabel.Text = 'Min Return Time';
            app.MinReturnTimeEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.MinReturnTimeEditField.Limits = [0 100];
            app.MinReturnTimeEditField.Value = 10;
            app.ModelreductionPanel = uipanel(app.UIFigure);
            app.ModelreductionPanel.Title = 'Model reduction';
            app.ReducemodelButton = uibutton(app.ModelreductionPanel, 'push');
            app.ReducemodelButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceModel, true);
            app.ReducemodelButton.Text = 'Reduce model';
            app.PutativeclustercountsEditFieldLabel = uilabel(app.ModelreductionPanel);
            app.PutativeclustercountsEditFieldLabel.Text = 'Putative cluster counts';
            app.PutativeclustercountsEditField = uieditfield(app.ModelreductionPanel, 'text');
            app.PutativeclustercountsEditField.Value = '100, 80, 60, 50, 40, 30, 20, 17, 15, 12, 10, 8';
            app.SeparatewithcommasLabel = uilabel(app.ModelreductionPanel);
            app.SeparatewithcommasLabel.Text = '(Separate with commas)';
            app.DistancemeasureEditFieldLabel = uilabel(app.ModelreductionPanel);
            app.DistancemeasureEditFieldLabel.Text = 'Distance measure';
            app.DistancetypeEditField = uieditfield(app.ModelreductionPanel, 'text');
            app.DistancetypeEditField.Value = 'correlation';
            app.MaxchecktimeEditFieldLabel = uilabel(app.ModelreductionPanel);
            app.MaxchecktimeEditFieldLabel.Text = 'Max check time';
            app.MaxchecktimeEditField = uieditfield(app.ModelreductionPanel, 'numeric');
            app.MaxchecktimeEditField.Limits = [2 100];
            app.MaxchecktimeEditField.Value = 10;
            app.FindloopsPanel = uipanel(app.UIFigure);
            app.FindloopsPanel.Title = 'Find loops';
            app.ProcessloopsButton = uibutton(app.FindloopsPanel, 'push');
            app.ProcessloopsButton.ButtonPushedFcn = createCallbackFcn(app, @FindLoops, true);
            app.ProcessloopsButton.Text = 'Find loops';
            app.SeparatewithcommasLabel_2 = uilabel(app.FindloopsPanel);
            app.SeparatewithcommasLabel_2.Text = '(Separate with commas)';
            app.PutativeloopcountsEditFieldLabel = uilabel(app.FindloopsPanel);
            app.PutativeloopcountsEditFieldLabel.Text = 'Putative loop counts';
            app.PutativeloopcountsEditField = uieditfield(app.FindloopsPanel, 'text');
            app.PutativeloopcountsEditField.Value = '5, 4, 3, 2';
            app.TargetstatecountEditFieldLabel = uilabel(app.FindloopsPanel);
            app.TargetstatecountEditFieldLabel.Text = 'Target state count';
            app.TargetstatecountEditField = uieditfield(app.FindloopsPanel, 'numeric');
            app.TargetstatecountEditField.Limits = [1 Inf];
            app.TargetstatecountEditField.Value = 100;
            app.SelectloopsButton = uibutton(app.FindloopsPanel, 'push');
            app.SelectloopsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLoops, true);
            app.SelectloopsButton.Text = 'Select loops';
            app.StartingloopsEditFieldLabel = uilabel(app.FindloopsPanel);
            app.StartingloopsEditFieldLabel.Text = 'Starting loops';
            app.StartingloopsEditField = uieditfield(app.FindloopsPanel, 'numeric');
            app.StartingloopsEditField.Limits = [1 Inf];
            app.StartingloopsEditField.Value = 6;
            app.TerminalStateCheckbox = uicheckbox(app.FindloopsPanel);
            app.TerminalStateCheckbox.Text = '';
            app.UseterminalstateLabel = uilabel(app.FindloopsPanel);
            app.UseterminalstateLabel.Text = 'Use terminal state';
            app.LoadMarkovPanel = uipanel(app.UIFigure);
            app.LoadMarkovPanel.Title = 'Load alternate Markov model';
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel_2 = uilabel(app.LoadMarkovPanel);
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel_2.Text = {'Load time series data into the workspace. Enter name of '; 'the variable to load, or click the search button to browse.'; 'STATES x STATES '};
            app.TransitionmatrixEditFieldLabel = uilabel(app.LoadMarkovPanel);
            app.TransitionmatrixEditFieldLabel.Text = 'Transition matrix';
            app.TransitionmatrixEditField = uieditfield(app.LoadMarkovPanel, 'text');
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel_2 = uilabel(app.LoadMarkovPanel);
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel_2.Text = 'STATES x CHANNELS';
            app.EmissionsEditFieldLabel = uilabel(app.LoadMarkovPanel);
            app.EmissionsEditFieldLabel.Text = 'Emissions';
            app.EmissionsEditField = uieditfield(app.LoadMarkovPanel, 'text');
            app.LoadButton_2 = uibutton(app.LoadMarkovPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadModelData, true);
            app.LoadButton_2.Text = 'Load';
            app.TransitionmatrixSearch = uibutton(app.LoadMarkovPanel, 'push');
            app.TransitionmatrixSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.TransitionmatrixSearch.Icon = 'MagGLass.png';
            app.TransitionmatrixSearch.Text = '';
            app.EmissionsSearch = uibutton(app.LoadMarkovPanel, 'push');
            app.EmissionsSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.EmissionsSearch.Icon = 'MagGLass.png';
            app.EmissionsSearch.Text = '';
            app.CancelButton_2 = uibutton(app.LoadMarkovPanel, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelLoadModel, true);
            app.CancelButton_2.Text = 'Cancel';
            app.STATESEditFieldLabel = uilabel(app.LoadMarkovPanel);
            app.STATESEditFieldLabel.Text = 'STATES';
            app.STATESEditField = uieditfield(app.LoadMarkovPanel, 'numeric');
            app.STATESEditField.Limits = [0 Inf];
            app.ValidatePanel = uipanel(app.UIFigure);
            app.ValidatePanel.Title = 'Validate';
            app.LoadcomparisonmodelButton = uibutton(app.ValidatePanel, 'push');
            app.LoadcomparisonmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModel, true);
            app.LoadcomparisonmodelButton.Text = 'Load comparison model';
            app.ClearcomparisonmodelButton = uibutton(app.ValidatePanel, 'push');
            app.ClearcomparisonmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ClearModel, true);
            app.ClearcomparisonmodelButton.Text = 'Clear comparison model';
            app.ValidateButton_2 = uibutton(app.ValidatePanel, 'push');
            app.ValidateButton_2.ButtonPushedFcn = createCallbackFcn(app, @Validate, true);
            app.ValidateButton_2.Text = 'Validate';
            app.ViewspectraButton = uibutton(app.ValidatePanel, 'push');
            app.ViewspectraButton.Text = 'View spectra';
            app.BestloopcountEditFieldLabel = uilabel(app.ValidatePanel);
            app.BestloopcountEditFieldLabel.Text = 'Best loop count';
            app.BestloopcountEditField = uieditfield(app.ValidatePanel, 'text');
            app.BestloopcountEditField.Editable = 'off';
            app.BeststatecountEditFieldLabel = uilabel(app.ValidatePanel);
            app.BeststatecountEditFieldLabel.Text = 'Best state count';
            app.BeststatecountEditField = uieditfield(app.ValidatePanel, 'text');
            app.BeststatecountEditField.Editable = 'off';
            app.LoadvalidationdataButton = uibutton(app.ValidatePanel, 'push');
            app.LoadvalidationdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadValidationData, true);
            app.LoadvalidationdataButton.Text = 'Load validation data';
            app.ClearvalidationdataButton = uibutton(app.ValidatePanel, 'push');
            app.ClearvalidationdataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearValidationData, true);
            app.ClearvalidationdataButton.Text = 'Clear validation data';
            app.YoumayoptionallyspecifyoutputsLabel_2 = uilabel(app.UIFigure);
            app.YoumayoptionallyspecifyoutputsLabel_2.Text = 'TIME x TICK (Defaults to 1 per tick)';
            app.TimeSearch = uibutton(app.UIFigure, 'push');
            app.TimeSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.TimeSearch.Icon = 'MagGLass.png';
            app.TimeSearch.Text = '';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.ChaaruCheckbox = uicheckbox(app.UIFigure);
            app.ChaaruCheckbox.Enable = 'off';
            app.ChaaruCheckbox.Text = '';
            app.LocaldimensionsLabel = uilabel(app.UIFigure);
            app.LocaldimensionsLabel.Text = 'Local dimensions';
            app.SlowLabel = uilabel(app.UIFigure);
            app.SlowLabel.Text = '(Slow)';
            app.MergeEndCheckbox = uicheckbox(app.UIFigure);
            app.MergeEndCheckbox.Text = '';
            app.MergetrialendsLabel = uilabel(app.UIFigure);
            app.MergetrialendsLabel.Text = 'Merge trial ends';
            app.TriallambdaEditFieldLabel = uilabel(app.UIFigure);
            app.TriallambdaEditFieldLabel.Text = 'Trial lambda';
            app.TriallambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TriallambdaEditField.Limits = [0 10];
            app.TriallambdaEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOOPER(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end