classdef LOOPER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainPanel                       matlab.ui.container.Panel
        SelectstepButtonGroup           matlab.ui.container.ButtonGroup
        LoadButton                      matlab.ui.control.RadioButton
        PreprocessingButton             matlab.ui.control.RadioButton
        DiffusionMapButton              matlab.ui.control.RadioButton
        ModelReductionButton            matlab.ui.control.RadioButton
        FindLoopsButton                 matlab.ui.control.RadioButton
        ValidateButton                  matlab.ui.control.RadioButton
        SavedataButton                  matlab.ui.control.Button
        ExperimentNameLabel             matlab.ui.control.Label
        ExperimentNameEditField         matlab.ui.control.EditField
        LoadmatfileButton               matlab.ui.control.Button
        PlottimesEditFieldLabel         matlab.ui.control.Label
        PlottimesEditField              matlab.ui.control.EditField
        RunallButton                    matlab.ui.control.Button
        RunclusteringButton             matlab.ui.control.Button
        SaveToWorkspaceButton           matlab.ui.control.Button
        LoadDataPanel                   matlab.ui.container.Panel
        LoaddataintoworkspaceandenterthenameofthevariableLabel  matlab.ui.control.Label
        DataEditFieldLabel              matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel  matlab.ui.control.Label
        InputsEditFieldLabel            matlab.ui.control.Label
        InputsEditField                 matlab.ui.control.EditField
        YoumayoptionallyspecifyoutputsLabel  matlab.ui.control.Label
        OutputsEditFieldLabel           matlab.ui.control.Label
        OutputsEditField                matlab.ui.control.EditField
        LoadworkspacedataButton         matlab.ui.control.Button
        DataSearch                      matlab.ui.control.Button
        InputsSearch                    matlab.ui.control.Button
        OutputsSearch                   matlab.ui.control.Button
        Cancel                          matlab.ui.control.Button
        FigureTabGroup                  matlab.ui.container.TabGroup
        FigureTab1                      matlab.ui.container.Tab
        Figure1                         matlab.ui.control.UIAxes
        SelectDataPanel                 matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        SelectDataList                  matlab.ui.control.ListBox
        SelectvariableButton            matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        ApplyprocessingButton           matlab.ui.control.Button
        ZScoreCheckbox                  matlab.ui.control.CheckBox
        SmoothingEditFieldLabel         matlab.ui.control.Label
        SmoothingEditField              matlab.ui.control.NumericEditField
        ZScoreLabel                     matlab.ui.control.Label
        EmbeddelayEditFieldLabel        matlab.ui.control.Label
        EmbeddelayEditField             matlab.ui.control.NumericEditField
        EmbedcountEditFieldLabel        matlab.ui.control.Label
        EmbedcountEditField             matlab.ui.control.NumericEditField
        InputlambdaEditFieldLabel       matlab.ui.control.Label
        InputlambdaEditField            matlab.ui.control.NumericEditField
        OutputlambdaEditFieldLabel      matlab.ui.control.Label
        OutputlambdaEditField           matlab.ui.control.NumericEditField
        MergeStartCheckbox              matlab.ui.control.CheckBox
        MergetrialstartLabel            matlab.ui.control.Label
        DiffusionmappingPanel           matlab.ui.container.Panel
        BuilddiffusionmapButton         matlab.ui.control.Button
        PreviewneighorhoodButton        matlab.ui.control.Button
        PreviewindexEditFieldLabel      matlab.ui.control.Label
        PreviewindexEditField           matlab.ui.control.NumericEditField
        NearestneighborsEditFieldLabel  matlab.ui.control.Label
        NearestneighborsEditField       matlab.ui.control.NumericEditField
        LocalDimensionsCheckbox         matlab.ui.control.CheckBox
        UselocaldimensionsLabel         matlab.ui.control.Label
        RepopulationdensityEditFieldLabel  matlab.ui.control.Label
        RepopulationdensityEditField    matlab.ui.control.NumericEditField
        MinReturnTimeEditFieldLabel     matlab.ui.control.Label
        MinReturnTimeEditField          matlab.ui.control.NumericEditField
        ModelreductionPanel             matlab.ui.container.Panel
        ReducemodelButton               matlab.ui.control.Button
        PutativeclustercountsEditFieldLabel  matlab.ui.control.Label
        PutativeclustercountsEditField  matlab.ui.control.EditField
        SeparatewithcommasLabel         matlab.ui.control.Label
        DistancemeasureEditFieldLabel   matlab.ui.control.Label
        DistancetypeEditField           matlab.ui.control.EditField
        MaxchecktimeEditFieldLabel      matlab.ui.control.Label
        MaxchecktimeEditField           matlab.ui.control.NumericEditField
        FindloopsPanel                  matlab.ui.container.Panel
        ProcessloopsButton              matlab.ui.control.Button
        SeparatewithcommasLabel_2       matlab.ui.control.Label
        PutativeloopcountsEditFieldLabel  matlab.ui.control.Label
        PutativeloopcountsEditField     matlab.ui.control.EditField
        TargetstatecountEditFieldLabel  matlab.ui.control.Label
        TargetstatecountEditField       matlab.ui.control.NumericEditField
        SelectloopsButton               matlab.ui.control.Button
        StartingloopsEditFieldLabel     matlab.ui.control.Label
        StartingloopsEditField          matlab.ui.control.NumericEditField
        TerminalStateCheckbox           matlab.ui.control.CheckBox
        UseterminalstateLabel           matlab.ui.control.Label
        LoadMarkovPanel                 matlab.ui.container.Panel
        LoaddataintoworkspaceandenterthenameofthevariableLabel_2  matlab.ui.control.Label
        TransitionmatrixEditFieldLabel  matlab.ui.control.Label
        TransitionmatrixEditField       matlab.ui.control.EditField
        YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel_2  matlab.ui.control.Label
        EmissionsEditFieldLabel         matlab.ui.control.Label
        EmissionsEditField              matlab.ui.control.EditField
        LoadButton_2                    matlab.ui.control.Button
        TransitionmatrixSearch          matlab.ui.control.Button
        EmissionsSearch                 matlab.ui.control.Button
        CancelButton_2                  matlab.ui.control.Button
        STATESEditFieldLabel            matlab.ui.control.Label
        STATESEditField                 matlab.ui.control.NumericEditField
        ValidatePanel                   matlab.ui.container.Panel
        LoadcomparisonmodelButton       matlab.ui.control.Button
        ClearcomparisonmodelButton      matlab.ui.control.Button
        ValidateButton_2                matlab.ui.control.Button
        ViewspectraButton               matlab.ui.control.Button
        BestloopcountEditFieldLabel     matlab.ui.control.Label
        BestloopcountEditField          matlab.ui.control.EditField
        BeststatecountEditFieldLabel    matlab.ui.control.Label
        BeststatecountEditField         matlab.ui.control.EditField
        LoadvalidationdataButton        matlab.ui.control.Button
        ClearvalidationdataButton       matlab.ui.control.Button
        YoumayoptionallyspecifyoutputsLabel_2  matlab.ui.control.Label
        TimeSearch                      matlab.ui.control.Button
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        ChaaruCheckbox                  matlab.ui.control.CheckBox
        LocaldimensionsLabel            matlab.ui.control.Label
        SlowLabel                       matlab.ui.control.Label
        MergeEndCheckbox                matlab.ui.control.CheckBox
        MergetrialendsLabel             matlab.ui.control.Label
        TriallambdaEditFieldLabel       matlab.ui.control.Label
        TriallambdaEditField            matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        ComponentList % List of components for searching
        ComponentNames % List of components for searching
        
        SelectVariableBox
        SelectVariableCaller
        
        CurrentState
        
        SavedData
        
        MainWindow
        
        FigureTabs
        Figures
        FigureIDs
        
        LoadingAlternative
        
        DefaultFilename = 'exp';
    end

    methods (Access = private)
        
        function warning(app, text)
            msgbox(text, 'Warning.')
        end
    
        %% In app plotting
        function setupTabs(app)
            app.FigureTabs = {};
            app.FigureTabs{1} = app.FigureTab1;
            app.Figures = {};
            app.Figures{1} = app.Figure1;
            
            app.FigureTabs{1}.Tag = '1';
            app.FigureIDs{1} = 1;
        end
    
        function tabID = newTab(app, figureNumber)
            app.FigureTabs{end+1} = uitab(app.FigureTabGroup);
            tabID = length(app.FigureTabs);
            app.FigureTabs{tabID}.Title = ['Figure ' num2str(figureNumber)];
            app.FigureTabs{tabID}.Tag = num2str(figureNumber);
            
            
            % Create Figures
            app.Figures{end+1} = uiaxes(app.FigureTabs{tabID});
            title(app.Figures{tabID}, '')
            xlabel(app.Figures{tabID}, 'X')
            ylabel(app.Figures{tabID}, 'Y')
            app.Figures{tabID}.Position = [11 6 584 484];
            
            app.FigureIDs{tabID} = num2str(figureNumber);
        end
    
        function appFigure = figure(app, figureNumber)
            figure(figureNumber)
            
            set(gcf, 'WindowStyle', 'Docked');
            
            appFigure = gca;
            
%             app.removeFigureNumber(figureNumber);
%             
%             tabID = app.newTab(figureNumber);
%             
%             app.FigureTabGroup.Visible = 'on';
%             app.FigureTabGroup.SelectedTab = app.FigureTabs{tabID};
%             appFigure = app.Figures{tabID};
            
% %             currentOrder = [];
% %             for i = 1:length(app.FigureTabGroup.Children)
% %                 currentOrder(i) = str2num(app.FigureTabGroup.Children(i).Tag);
% %             end
% %             [~, sortOrder] = sort(currentOrder);
% %             tempArray = app.FigureTabGroup.Children;
% %             tempArray = tempArray(sortOrder);
%             
% %             for i = 1:length(tempArray)
% %                 tempArray(i).Parent = [];
% %             end
% %             for i = 1:length(tempArray)
% %                 tempArray(i).Parent = app.FigureTabGroup;
% %             end
% %             app.FigureTabGroup.Children = tempArray;
% 
%             
% %             if length(app.FigureTabGroup.Children) > 1
% %                 newOrder = 1:length(app.FigureTabGroup.Children)-1;
% %                 newOrder = [newOrder(1:figureNumber-1) length(app.FigureTabGroup.Children) newOrder(figureNumber:end)];
% %                 app.FigureTabGroup.Children = app.FigureTabGroup.Children(newOrder);
% %             end
        end
        
        function title(app, figure, titleString)
            figure.Parent.Name = titleString; 
            figure.Parent.NumberTitle = 'off';
            
%             for i = 1:length(app.Figures)
%                 if app.Figures{i} == figure
%                     app.FigureTabs{i}.Title = titleString;
%                     break;
%                 end
%             end
        end
    
        function removeFigureNumber(app, figureNumber)
%             for i = 1:length(app.FigureTabs)
%                 if strcmp(app.FigureTabs{i}.Tag, num2str(figureNumber))
%                     delete(app.FigureTabs{i})
%                     app.FigureTabs(i) = [];
%                     app.Figures(i) = [];
%                     app.FigureIDs(i) = [];
%                     break;
%                 end
%             end
        end
    
        function removeFigure(app, figure)
            for i = 1:length(app.Figures)
                if app.Figures{i} == figure
                    app.FigureTabs(i) = [];
                    app.Figures(i) = [];
                    app.FigureIDs(i) = [];
                    break;
                end
            end
        end
    
        function removeAllFigures(app)
%             close all;
            
%             for i = 1:length(app.Figures)
%                 delete(app.FigureTabs{i})
%             end
%             app.FigureTabs = {};
%             app.Figures = [];
%             app.FigureIDs = [];
%             app.FigureTabGroup.Visible = 'off';
        end
    
        %% other general functions
        
        function results = findComponent(app, name)
            eval(['results = app.' name ';']);
        end
    
        %% app functions
    
        function setupState(app)
            components = properties(app);
            for i = 1:length(components)
                thisComponent = findComponent(app, components{i});
                if isvalid(thisComponent) && strcmp(thisComponent.Type, 'uipanel')
                    thisComponent.Visible = 'off';
                end
            end
            
            app.MainPanel.Visible = 'on';
            
            if strcmp(app.CurrentState, 'LoadButton')
                app.showLoadPanel(false);
            elseif strcmp(app.CurrentState, 'PreprocessingButton')
                app.PreprocessingPanel.Visible = 'on';
            elseif strcmp(app.CurrentState, 'DiffusionMapButton')
                app.DiffusionmappingPanel.Visible = 'on';
            elseif strcmp(app.CurrentState, 'ModelReductionButton')
                app.ModelreductionPanel.Visible = 'on';
            elseif strcmp(app.CurrentState, 'FindLoopsButton')
                app.FindloopsPanel.Visible = 'on';
            elseif strcmp(app.CurrentState, 'ValidateButton')
                app.ValidatePanel.Visible = 'on';
            end
        end
        
        
        function dataExists = hasData(app, dataName)
            dataExists = isfield(app.SavedData, dataName);
        end
    
        function removeData(app, dataName)
            if isfield(app.SavedData, dataName)
                app.SavedData = rmfield(app.SavedData, dataName);
            end
        end
    
        function checkLoadedData(app)
            if (app.hasData('test'))
            end
            
            app.checkStepValidity();
        end
    
        function filename = getDefaultFileLocation(app)
            [currentDirectory,~,~] = fileparts(mfilename('fullpath'));
            
            filename = [currentDirectory '/backup.mat'];
        end
    
        function [filename, path] = getDefaultExperimentName(app)
            [currentDirectory,~,~] = fileparts(mfilename('fullpath'));
            
            allFiles = dir([currentDirectory '/' app.DefaultFilename '*.mat']);
            finalNumber = length(allFiles)+1;
            for i = 1:length(allFiles)
                nextNumber = regexp(allFiles(i).name, [app.DefaultFilename '(\d).mat'], 'tokens');
                if ~isempty(nextNumber) && str2double(nextNumber{1}{1}) > i
                    finalNumber = i;
                    break;
                end
            end
            
            filename = [app.DefaultFilename num2str(finalNumber) '.mat'];
            path = [currentDirectory '/' filename];
        end
    
        function saveLoadedData(app, filename)
            if strcmp(filename, '')
                filename = app.getDefaultFileLocation();
            end
            
            saveData = app.SavedData;
            
            save(filename, 'saveData');
        end
    
        function checkStepValidity(app)
            if isfield(app.SavedData,'RawData')
                app.PlottimesEditField.Enable = true;
                
                app.setupPlotTimes()
            else
                app.PlottimesEditField.Enable = false;
            end
            
            app.LoadButton.Enable = true;
            app.ApplyprocessingButton.Enable = isfield(app.SavedData,'RawData');
            app.BuilddiffusionmapButton.Enable = isfield(app.SavedData,'TimeSeries');
            app.ReducemodelButton.Enable = isfield(app.SavedData,'AsymmetricMap');
            app.ProcessloopsButton.Enable = isfield(app.SavedData,'ReducedMatrix');
            
            app.RunallButton.Enable = isfield(app.SavedData,'RawData');
            app.RunclusteringButton.Enable = isfield(app.SavedData,'AsymmetricMap');
            
            
%             app.PreprocessingButton.Enable = isfield(app.SavedData,'RawData');
%             app.DiffusionMapButton.Enable = isfield(app.SavedData,'TimeSeries');
%             app.ModelReductionButton.Enable = isfield(app.SavedData,'AsymmetricMap');
            app.ClearcomparisonmodelButton.Enable = isfield(app.SavedData,'AltTransitions');
            app.ClearvalidationdataButton.Enable = isfield(app.SavedData,'AltDynamics');
%             app.FindLoopsButton.Enable = isfield(app.SavedData,'ReducedMatrix');
            app.ValidateButton.Enable = isfield(app.SavedData,'LogLikelihoods');
            if isfield(app.SavedData,'LogLikelihoods')
                app.BestloopcountEditField.Value = num2str(app.SavedData.BestLoopCount);
                app.BeststatecountEditField.Value = num2str(app.SavedData.BestStateCount);
            end
        end
    
        function setupPlotTimes(app)
            if isfield(app.SavedData,'TimeSeries')
%                 trialLengths = 
            elseif isfield(app.SavedData,'RawData')
                
            end
        end
    
        function [pcaBasis, thisPlot, trialIndices] = plotPCA(app, shouldPlot, data, trialEnds)
            if ~exist('shouldPlot', 'var') || isempty(shouldPlot)
                shouldPlot = 1;
            end
            
            if ~exist('data', 'var') || isempty(data)
                data = [];
            end
            
            if ~exist('trialEnds', 'var') || isempty(trialEnds)
                trialEnds = [];
            end
            
            if ~isempty(data)
                timeSeries = data;
                trialEnds = trialEnds;
%             elseif isfield(app.SavedData,'FinalStream')
%                 timeSeries = app.SavedData.FinalStream;
%                 trialEnds = [];
            elseif isfield(app.SavedData,'TimeSeries')
                timeSeries = app.SavedData.TimeSeries;
                trialEnds = app.SavedData.TrialSwitches;
            elseif isfield(app.SavedData,'RawData')
                timeSeries = app.SavedData.RawData;
                trialEnds = [];
                if isfield(app.SavedData,'RawTrialSwitches')
                    trialEnds = app.SavedData.RawTrialSwitches;
                end
            end
            
            trialCount = length(trialEnds);
            maxTrialLength = max(diff(trialEnds));
            trialStarts = [1 trialEnds+1];
            trialStarts(end) = [];
            numDimensions = size(timeSeries, 1);
            
            if isempty(app.PlottimesEditField.Value)
                maxTrialLength = size(timeSeries,2);
                
                plotIndices = 1:maxTrialLength;
            else
                plotIndices = eval(app.PlottimesEditField.Value);
            end
            
            trialIndices = [];
            for i = 1:trialCount
                thisIndices = trialStarts(i) - 1 + plotIndices;
                thisIndices(thisIndices > trialEnds(i)) = [];
                
                trialIndices = [trialIndices thisIndices];
            end
            
            badIndices = setdiff(1:size(timeSeries,2), trialIndices);
            badIndices = [badIndices, trialEnds];
            timeSeries(:, badIndices) = nan;
            
            thisPlot = [];
            if shouldPlot
                thisPlot = app.figure(1);   
                hold off;
            end
            if numDimensions == 1
                pcaBasis = eye(1,3);
                
                if shouldPlot
                    plot(thisPlot, timeSeries(1,:))
                
                    xlabel('Time Series');
                    ylabel('Time (timesteps)');
                end
            elseif numDimensions == 2
                pcaBasis = eye(2,3);
                
                if shouldPlot
                    plot(thisPlot, timeSeries(1,:), timeSeries(2,:))
                
                    xlabel('PC1');
                    ylabel('PC2');
                end
            else
                pcaTimeSeries = timeSeries;
                pcaTimeSeries(:,badIndices) = [];
                [pcaBasis, pcaOutputs] = pca(pcaTimeSeries', 'NumComponents', 3);
            
                if shouldPlot
                    h = plot3(thisPlot, timeSeries'*pcaBasis(:,1), timeSeries'*pcaBasis(:,2), timeSeries'*pcaBasis(:,3));
                    h.Color(4) = 0.2;
                    
                    xlabel('PC1');
                    ylabel('PC2');
                    zlabel('PC3');
                end
            end
        end
    
        function showLoadPanel(app, loadingAlternative)
            app.LoadingAlternative = loadingAlternative;
            app.LoadmatfileButton.Visible = ~loadingAlternative;
            app.Cancel.Visible = loadingAlternative;
            app.LoadDataPanel.Visible = 'on';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app, oldSettings, shouldRun, dynamics, inputs, outputs, parameters)
            app.CurrentState = 'LoadButton';
            
            app.initSettings();
            
            setupState(app);
            
            components = properties(app);
            for i = 1:length(components)
                thisComponent = findComponent(app, components{i});
                eval(['thisComponent.Tag = ''' components{i} ''';']);
            end
            
            app.SavedData = struct;
            app.MainWindow = app.UIFigure;
            
            filename = app.getDefaultExperimentName();
            app.ExperimentNameEditField.Value = filename(1:end-4);
            
            app.setupTabs();
            
            reloadSettings = false;
            if exist('oldSettings') && ~isempty(oldSettings)
                app.SavedData = oldSettings;
                reloadSettings = true;
            end
            
            if exist('dynamics') && ~isempty(dynamics)                
                rawData = convertToCell(dynamics);
                
                lastEnd = 0;
                for i = 1:length(rawData)
                    trialData(lastEnd + (1:size(rawData{i}, 2))) = i;
                    
                    lastEnd = lastEnd + size(rawData{i}, 2);
                end
                
                rawData = mergeData(rawData);
                
                app.SavedData.RawData = rawData;
                if exist('inputs') && ~isempty(inputs)
                    inputs = mergeData(convertToCell(inputs));
                    app.SavedData.Inputs = inputs;
                end
                if exist('outputs') && ~isempty(outputs)
                    outputs = mergeData(convertToCell(outputs));
                    app.SavedData.Outputs = outputs;
                end
                if ~isempty(trialData)
                    app.SavedData.TrialData = trialData;
                    app.SavedData.RawTrialSwitches = find(diff(trialData) ~= 0);
                end
            end
            
            if exist('parameters') && ~isempty(parameters)
                if ~app.hasData('PreprocessData')
                    app.SavedData.PreprocessData = [];
                end
                
                fields = fieldnames(parameters);
                
                for i = 1:length(fields)
                    if strcmp(fields{i}, 'PreprocessData')
                        parameters
                        preprocessParameters = parameters.PreprocessData;
                        preprocessFields = fieldnames(preprocessParameters);
                        
                        for j = 1:length(preprocessFields)
                            app.SavedData.PreprocessData.(preprocessFields{j}) = preprocessParameters.(preprocessFields{j});
                        end
                    else
                        app.SavedData.(fields{i}) = parameters.(fields{i});
                    end
                end
            end
            
            test = 1;
            
            app.checkLoadedData();
            
            if reloadSettings
                app.setUpUI();
            end
            
            if exist('shouldRun') && ~isempty(shouldRun) && shouldRun
                app.RunAllSteps();
                
                app.SaveToWorkSpace();
                
                close(app.UIFigure);
            end
        end
            
        function initSettings(app)
            if ~app.hasData('RepopulateDensity')
                app.SavedData.RepopulateDensity = 0.95;
            end
            
            if ~app.hasData('MinimumReturnTime')
                app.SavedData.MinimumReturnTime = 10;
            end
            
            if ~app.hasData('MaxCheckTime')
                app.SavedData.MaxCheckTime = 5;
            end
        end

        % Button pushed function: DataSearch, EmissionsSearch, 
        % InputsSearch, OutputsSearch, TimeSearch, 
        % TransitionmatrixSearch
        function SearchWorkspace(app, event)
            fillBoxName = event.Source.Tag;
            fillBoxName = [fillBoxName(1:end-6) 'EditField'];
            fillBox = findComponent(app, fillBoxName);            
            
            app.SelectVariableBox = fillBox;
            app.SelectVariableCaller = event.Source.Parent;
            
            %set up selector UI
            app.SelectVariableCaller.Visible = 'off';
            app.SelectDataPanel.Visible = 'on';
                        
            data = evalin('base', 'whos');
            
            sortType = "horizontal";
            if fillBox == app.TransitionmatrixEditField
                sortType = "square";
            elseif fillBox == app.EmissionsEditField
                sortType = "states";
            end
            
            dataInfo = {};
            dataScores = [];
            dataNames = {data.name};
            for i = 1:length(data)
                if evalin('base', ['iscell(' data(i).name ')'])
                    dataSize = evalin('base', ['size(' data(i).name '{1})']);
                    isNumeric = isnumeric(evalin('base', [data(i).name '{1}']));
                    class = evalin('base', ['class(' data(i).name '{1})']);
                    
                    dataInfo{i} = [data(i).name ' ' num2str(dataSize(1)) 'x' num2str(dataSize(2)) 'x' num2str(max(data(i).size)) ' ' class];
                elseif size(data(i),3) > 1
                    dataSize = data(i).size;
                    isNumeric = isnumeric(evalin('base', data(i).name));
                
                    dataInfo{i} = [data(i).name ' ' num2str(dataSize(1)) 'x' num2str(dataSize(2)) 'x' num2str(dataSize(3)) ' ' data(i).class];
                else
                    dataInfo{i} = [data(i).name ' ' num2str(data(i).size(1)) 'x' num2str(data(i).size(2)) ' ' data(i).class];
                
                    dataSize = data(i).size;
                    isNumeric = isnumeric(evalin('base', data(i).name));
                end
                
                
                if sortType == "square"
                    if app.STATESEditField.Value > 0                       
                        numStates = app.STATESEditField.Value;
                        
                        dataScores(i) = 1 / (1 + abs(dataSize(1) - numStates)) / (1 + abs(dataSize(2) - dataSize(1)));
                    else
                        dataScores(i) = dataSize(1) / (1 + abs(dataSize(2) - dataSize(1)));
                    end
                elseif sortType == "states"
                    if app.STATESEditField.Value > 0                       
                        numStates = app.STATESEditField.Value;
                        
                        dataScores(i) = dataSize(2) / (1 + abs(numStates - dataSize(1)))^2;
                    else
                        dataScores(i) = dataSize(2)^2 / dataSize(1);
                    end
                else
                    dataScores(i) = dataSize(2)^2 / dataSize(1);
                end
                
                if ~isNumeric || dataSize(1) == 0 || dataSize(2) == 0
                    dataScores(i) = 0;
                end
            end
            
            [sortedValues, sortOrder] = sort(dataScores, 'descend');
            
            sortOrder(sortedValues == 0) = [];
            sortOrder = sortOrder(1:min(length(sortOrder), 20));
            
            dataInfo = dataInfo(sortOrder);
            dataNames = dataNames(sortOrder);
            
            app.SelectDataList.ItemsData = dataNames;
            app.SelectDataList.Items = dataInfo;
        end

        % Button pushed function: SelectvariableButton
        function SelectVariable(app, event)
            app.SelectVariableBox.Value = app.SelectDataList.Value;
            
            app.SelectVariableCaller.Visible = 'on';
            app.SelectDataPanel.Visible = 'off';
        end

        % Selection changed function: SelectstepButtonGroup
        function StepSelected(app, event)
            selectedButton = app.SelectstepButtonGroup.SelectedObject;
            app.CurrentState = selectedButton.Tag;
            
            setupState(app);
            
            if strcmp(app.CurrentState, 'LoadButton')
                app.plotLoadedData();
            elseif strcmp(app.CurrentState, 'PreprocessingButton')
                app.loadPreprocessing(true);
            elseif strcmp(app.CurrentState, 'DiffusionMapButton')
                if (app.hasData("AsymmetricMap"))
                    app.plotDiffusionMap();
                else
                    app.plotProcessedData();
                end
            elseif strcmp(app.CurrentState, 'ModelReductionButton')
                
            elseif strcmp(app.CurrentState, 'FindLoopsButton')
            elseif strcmp(app.CurrentState, 'ValidateButton')
            end
            
            app.setUpUI()
        end
        
        function setUpUI(app)
            
            app.loadPreprocessing(false);
            
            if isfield(app.SavedData,'NearestNeighbors')
                app.NearestneighborsEditField.Value = app.SavedData.NearestNeighbors;
                app.LocalDimensionsCheckbox.Value = app.SavedData.UseLocalDimensions;
                app.RepopulationdensityEditField.Value = app.SavedData.RepopulateDensity;
                app.MinReturnTimeEditField.Value = app.SavedData.MinimumReturnTime;
            end
            
            if isfield(app.SavedData,'PutativeClusterCounts')
                clusterList = num2str(app.SavedData.PutativeClusterCounts(1));
                for i = 2:length(app.SavedData.PutativeClusterCounts)
                    clusterList = [clusterList ', ' num2str(app.SavedData.PutativeClusterCounts(i))];
                end
                
                app.PutativeclustercountsEditField.Value = clusterList;
                app.DistancetypeEditField.Value = app.SavedData.DistanceType;
                app.MaxchecktimeEditField.Value = app.SavedData.MaxCheckTime;
            end
            
            if isfield(app.SavedData,'PutativeLoopCounts')
                loopList = num2str(app.SavedData.PutativeLoopCounts(1));
                for i = 2:length(app.SavedData.PutativeLoopCounts)
                    loopList = [loopList ', ' num2str(app.SavedData.PutativeLoopCounts(i))];
                end
                
                app.PutativeloopcountsEditField.Value = loopList;
                
                if isfield(app.SavedData,'UseTerminalState')
                    app.TerminalStateCheckbox.Value = app.SavedData.UseTerminalState;
                end
                
                if isfield(app.SavedData,'TotalStates')
                    app.TargetstatecountEditField.Value = app.SavedData.TotalStates;
                end
            end
            
            if isfield(app.SavedData,'LogLikelihoods')
                app.BestloopcountEditField.Value = num2str(app.SavedData.BestLoopCount);
                app.BeststatecountEditField.Value = num2str(app.SavedData.BestStateCount);
            end
            app.ClearcomparisonmodelButton.Enable = isfield(app.SavedData,'AltTransitions');
            app.ClearvalidationdataButton.Enable = isfield(app.SavedData,'AltDynamics');
        end

        % Value changed function: SelectDataList
        function ClickData(app, event)
            persistent checked
            persistent tempValue
            
            if isempty(checked)
                  checked = 1;
                  tempValue = app.SelectDataList.Value;% if you want to use this value later 
                  app.SelectDataList.Value = {};
                  pause(0.25); %Add a delay to distinguish single click from a double click
                  if checked == 1
                      checked = [];
                      app.SelectDataList.Value = tempValue;
                  end
            else
                  
                  
                  if tempValue == app.SelectDataList.Value
                      checked = [];
                      app.SelectDataList.Value = tempValue;
                      app.SelectVariable(event);
                  end
            end
        end

        % Button pushed function: LoadworkspacedataButton
        function LoadData(app, event)
            if app.LoadingAlternative        
                [rawData, inputs, outputs, trialData, success] = app.processMultipleTrials('Alt');
                if success
                    [app.SavedData.AltDynamics, ~, ~, ~, ~] = preprocessData(rawData, inputs, app.SavedData.PreprocessData.InputLambda, outputs, app.SavedData.PreprocessData.OutputLambda, trialData, app.SavedData.PreprocessData.TrialLambda, false, app.SavedData.PreprocessData.Smoothing, app.SavedData.PreprocessData.ZScore, app.SavedData.PreprocessData.DelayTime, app.SavedData.PreprocessData.DelayCount, app.SavedData.DataMean, app.SavedData.DataSTD);
                end
                
                app.ValidatePanel.Visible = 'on';
                app.LoadDataPanel.Visible = 'off';
                
            else
                [rawData, inputs, outputs, trialData, success] = app.processMultipleTrials('');
                if success
                    app.plotLoadedData();
%                     app.saveLoadedData('');
                end
            end
            
            app.checkStepValidity();
        end
    
        function [rawData, inputs, outputs, trialData, success] = processMultipleTrials(app, affix)
            success = false;
            rawData = [];
            trialData = [];
            inputs = [];
            outputs = [];
            
            app.removeData([affix 'RawData']);
            app.removeData([affix 'Inputs']);
            app.removeData([affix 'Outputs']);
            app.removeData([affix 'TrialData']);
               
            if evalin('base', ['exist(''' app.DataEditField.Value ''', ''var'')'])
                rawData = evalin('base', app.DataEditField.Value);
                rawData = convertToCell(rawData);
                
                lastEnd = 0;
                for i = 1:length(rawData)
                    trialData(lastEnd + (1:size(rawData{i}, 2))) = i;
                    
                    lastEnd = lastEnd + size(rawData{i}, 2);
                end
                
                rawData = mergeData(rawData);
                
%                 if evalin('base', ['exist(''' app.TimeEditField.Value ''', ''var'')'])
%                     trialData = evalin('base', app.InputsEditField.Value);
%                     trialData = app.mergeData(app.convertToCell(inputs));
%                 end
                
                if evalin('base', ['exist(''' app.InputsEditField.Value ''', ''var'')'])
                    inputs = evalin('base', app.InputsEditField.Value);
                    inputs = mergeData(convertToCell(inputs));
                end
                
                if evalin('base', ['exist(''' app.OutputsEditField.Value ''', ''var'')'])
                    outputs = evalin('base', app.OutputsEditField.Value);
                    outputs = mergeData(convertToCell(outputs));
                end
                
                app.SavedData.([affix 'RawData']) = rawData;
                if ~isempty(inputs)
                    app.SavedData.([affix 'Inputs']) = inputs;
                end
                if ~isempty(outputs)
                    app.SavedData.([affix 'Outputs']) = outputs;
                end
                if ~isempty(trialData)
                    app.SavedData.([affix 'TrialData']) = trialData;
                    app.SavedData.([affix 'RawTrialSwitches']) = find(diff(trialData) ~= 0);
                end
                
                success = true;
            else
                app.warning(['Variable ' app.DataEditField.Value ' not found in workspace.'])
            end
        end


        function plotLoadedData(app)
            app.removeAllFigures();
            
            if app.hasData('RawData')
                thisPlot = app.figure(1);
                size(app.SavedData.RawData)
                imagesc(thisPlot, app.SavedData.RawData)
                app.title(thisPlot, 'Data')
                ylabel(thisPlot, 'Data Channel #')
                xlabel(thisPlot, 'Time (steps)')
                xlim(thisPlot, [0 length(app.SavedData.RawData)])
                
                if app.hasData('RawTrialSwitches')
                    RawTrialSwitches = app.SavedData.RawTrialSwitches;
                else
                    RawTrialSwitches = [];
                    
                    app.SavedData.TrialData = [];
                    app.SavedData.RawTrialSwitches = [];
                end
                
                if max(app.SavedData.TrialData > 1)
                    yLimit = ylim(thisPlot);
                    hold on;
                    for i = 1:length(RawTrialSwitches)
                        plot(ones(1,2)*RawTrialSwitches(i), yLimit, 'r', 'LineWidth', 1);
                    end
                end


                if app.hasData('Inputs')                    
                    thisPlot = app.figure(2);
                    imagesc(thisPlot, app.SavedData.Inputs)
                    app.title(thisPlot, 'Input')
                    ylabel(thisPlot, 'Input Channel #')
                    xlabel(thisPlot, 'Time (steps)')
                    xlim(thisPlot, [0 length(app.SavedData.Inputs)])
                    
                    if max(app.SavedData.TrialData > 1)
                        yLimit = ylim(thisPlot);
                        hold on;
                        for i = 1:length(RawTrialSwitches)
                            plot(ones(1,2)*RawTrialSwitches(i), yLimit, 'r', 'LineWidth', 1);
                        end
                    end
                else
                    app.removeFigureNumber(2);
                end
                
                if app.hasData('Outputs')                    
                    thisPlot = app.figure(3);
                    imagesc(thisPlot, app.SavedData.Outputs)
                    app.title(thisPlot, 'Outputs')
                    ylabel(thisPlot, 'Output Channel #')
                    xlabel(thisPlot, 'Time (steps)')
                    xlim(thisPlot, [0 length(app.SavedData.Outputs)])
                    
                    if max(app.SavedData.TrialData > 1)
                        yLimit = ylim(thisPlot);
                        hold on;
                        for i = 1:length(RawTrialSwitches)
                            plot(ones(1,2)*RawTrialSwitches(i), yLimit, 'r', 'LineWidth', 1);
                        end
                    end
                else
                    app.removeFigureNumber(3);
                end
                
%                 if app.hasData('TrialData')                    
%                     thisPlot = app.figure(3);
%                     imagesc(thisPlot, app.SavedData.TrialData)
%                     app.title(thisPlot, 'Timecourse')
%                     ylabel(thisPlot, 'Time (au)')
%                     xlabel(thisPlot, 'Frame #')
%                     xlim(thisPlot, [0 length(app.SavedData.TrialData)])
%                 else
%                     app.removeFigureNumber(3);
%                 end
            end
        end

        % Button pushed function: ApplyprocessingButton
        function ApplyPreprocessing(app, event)
            shouldZScore = app.ZScoreCheckbox.Value;
            smoothingAmount = app.SmoothingEditField.Value;
            delayTime = app.EmbeddelayEditField.Value;
            delayCount = app.EmbedcountEditField.Value;
            useChaaru = app.ChaaruCheckbox.Value;
            shouldMergeStarts = app.MergeStartCheckbox.Value;
            shouldMergeEnds = app.MergeEndCheckbox.Value;
            inputLambda = app.InputlambdaEditField.Value;
            outputLambda = app.OutputlambdaEditField.Value;
            trialLambda = app.TriallambdaEditField.Value;
            
            app.removeData('TimeSeries');
            
            rawData = app.SavedData.RawData;
            rawInputs = [];
            rawOutputs = [];
            rawTrialData = [];
            if app.hasData('Inputs')
                rawInputs = app.SavedData.Inputs;
            end
            if app.hasData('Outputs')
                rawOutputs = app.SavedData.Outputs;
            end
%             if app.hasData('TrialData')
%                 RawTrialSwitches = app.SavedData.RawTrialSwitches;
%                 
%                 rawTrialData = zeros(1, size(rawData,2));
%                 
%                 if shouldMergeStarts || 1
%                     rawTrialData(1) = 1;
%                     rawTrialData(RawTrialSwitches + 1) = 1;
%                 end
%                 
% %                 if shouldMergeEnds
% %                     rawTrialData(end) = -1;
% %                     rawTrialData(RawTrialSwitches) = -1;
% %                 end
%             end
            
            [tempData, trialData, procesedTrialSwitches, dataMean, dataSTD] = preprocessData(rawData, rawInputs, inputLambda, rawOutputs, outputLambda, app.SavedData.TrialData, trialLambda, true, smoothingAmount, shouldZScore, delayTime, delayCount, [], []);
            
            app.SavedData.DataMean = dataMean;
            app.SavedData.DataSTD = dataSTD;
            app.SavedData.TrialTimeSeries = trialData;
            app.SavedData.TrialSwitches = procesedTrialSwitches;
            app.SavedData.TimeSeries = tempData;
            app.SavedData.PreprocessData = struct;
            app.SavedData.PreprocessData.Smoothing = smoothingAmount;
            app.SavedData.PreprocessData.ZScore = shouldZScore;
            app.SavedData.PreprocessData.DelayTime = delayTime;
            app.SavedData.PreprocessData.DelayCount = delayCount;
            app.SavedData.PreprocessData.InputLambda = inputLambda;
            app.SavedData.PreprocessData.OutputLambda = outputLambda;
            app.SavedData.PreprocessData.TrialLambda = trialLambda;
            app.SavedData.PreprocessData.MergeStarts = shouldMergeStarts;
            app.SavedData.PreprocessData.MergeEnds = shouldMergeEnds;
            
            app.loadPreprocessing(true);
            app.checkStepValidity();
%             app.saveLoadedData('');
        end


        function loadPreprocessing(app, shouldPlot)
            if (app.hasData("PreprocessData"))                
                app.ZScoreCheckbox.Value = app.SavedData.PreprocessData.ZScore;
                app.SmoothingEditField.Value = app.SavedData.PreprocessData.Smoothing;
                app.EmbeddelayEditField.Value = app.SavedData.PreprocessData.DelayTime;
                app.EmbedcountEditField.Value = app.SavedData.PreprocessData.DelayCount;
                app.InputlambdaEditField.Value = app.SavedData.PreprocessData.InputLambda;
                app.OutputlambdaEditField.Value = app.SavedData.PreprocessData.OutputLambda;
                app.TriallambdaEditField.Value = app.SavedData.PreprocessData.TrialLambda;
                
                app.MergeStartCheckbox.Value = app.SavedData.PreprocessData.MergeStarts;
                app.MergeEndCheckbox.Value = app.SavedData.PreprocessData.MergeEnds;
            end
            
            app.InputlambdaEditField.Enable = app.hasData('Inputs');
            app.OutputlambdaEditField.Enable = app.hasData('Outputs');
            app.TriallambdaEditField.Enable = app.hasData('TrialData') && max(app.SavedData.TrialData) > 1;
            
%             if app.hasData('TrialData') && max(app.SavedData.TrialData) > 1
%                 app.MergeStartCheckbox.Enable = true;
%                 app.MergeEndCheckbox.Enable = true;
%             else
%                 app.MergeStartCheckbox.Enable = false;
%                 app.MergeEndCheckbox.Enable = false;
%                 
%                 app.MergeStartCheckbox.Value = false;
%                 app.MergeEndCheckbox.Value = false;
%             end
            
            if shouldPlot && app.hasData('TimeSeries')
                app.plotProcessedData();
            end
        end
    
        function plotProcessedData(app)
            app.removeAllFigures();
            
            if app.hasData('TimeSeries')
                [pcaBasis, thisPlot, displayIndices] = app.plotPCA();
                
                app.title(thisPlot, 'Time Series')
            end
        end

        % Button pushed function: LoadmatfileButton
        function LoadFile(app, event)
            [file, path] = uigetfile('*.mat', 'Select saved data', app.getDefaultFileLocation());
            app.MainWindow.Visible = 'off';
            app.MainWindow.Visible = 'on';

            if file ~= 0
                loadFile = [path '/' file];
                
                thisData = load(loadFile);
                app.SavedData = thisData.saveData;
                
                app.initSettings();
                
                if app.hasData('ExperimentName')
                    app.ExperimentNameEditField.Value = app.SavedData.ExperimentName;
                end
                
                if ~contains(loadFile, 'backup.mat')
                    app.SavedData.Filename = loadFile;
                end
                
                app.plotLoadedData();            
                app.checkStepValidity();
                
                app.setUpUI();
            end
        end

        % Button pushed function: CancelButton
        function SelectCancel(app, event)
            app.SelectVariableCaller.Visible = 'on';
            app.SelectDataPanel.Visible = 'off';
        end

        % Button pushed function: SavedataButton
        function SaveFile(app, event)
            [currentDirectory,~,~] = fileparts(mfilename('fullpath'));
            
            if app.hasData('Filename')
                [file, path] = uiputfile('*.mat', 'Save data', app.SavedData.Filename);
            else
                [file, path] = uiputfile('*.mat', 'Save data', [currentDirectory '/' matlab.lang.makeValidName(app.ExperimentNameEditField.Value,'ReplacementStyle','delete') '.mat']);
            end
            app.MainWindow.Visible = 'off';
            app.MainWindow.Visible = 'on';
            
            if file ~= 0
                app.SavedData.ExperimentName = app.ExperimentNameEditField.Value;
                app.SavedData.Filename = [path '/' file];
    
                app.saveLoadedData(app.SavedData.Filename);     
            end
%         end
%     
%         function ApplySettings(app)
%             if (app.hasData("PreprocessData"))                
%                 app.ZScoreCheckbox.Value = app.SavedData.PreprocessData.ZScore;
%                 app.SmoothingEditField.Value = app.SavedData.PreprocessData.Smoothing;
%                 app.EmbeddelayEditField.Value = app.SavedData.PreprocessData.DelayTime;
%                 app.EmbedcountEditField.Value = app.SavedData.PreprocessData.DelayCount;
%             end
            
            
        end

        % Button pushed function: PreviewneighorhoodButton
        function PreviewNeighborhood(app, event)
        
            cursorMode = datacursormode(gcf);
            datatips = cursorMode.getCursorInfo();
            
            previewIndex = 0;
            if (length(datatips) > 0 && isfield(datatips(1), 'DataIndex')) 
                    previewIndex = datatips(1).DataIndex;
            elseif app.PreviewindexEditField.Value > 0
                previewIndex = app.PreviewindexEditField.Value;       
            else
                app.plotProcessedData();
                
                app.warning("Please select a point on the plot using the Data Cursor, or input a preview index.");
            end
            
            app.SavedData.NearestNeighbors = app.NearestneighborsEditField.Value;
            app.SavedData.UseLocalDimensions = app.LocalDimensionsCheckbox.Value;
            app.SavedData.RepopulateDensity = app.RepopulationdensityEditField.Value;
            app.SavedData.MinimumReturnTime = app.MinReturnTimeEditField.Value;
            
            nearestNeighbors = app.SavedData.NearestNeighbors;
            useLocalDimensions = app.SavedData.UseLocalDimensions;
            
            minReturnTime = app.SavedData.MinimumReturnTime;
            
%             smoothSigma = 1;
%             x = -ceil(3*smoothSigma):ceil(3*smoothSigma);
%             kernel = -x.*exp(-x.^2/(2*smoothSigma^2))/(smoothSigma^3*sqrt(2*pi));
%             
%             dynamicsDiff = [];
%             for j = 1:length(app.SavedData.TrialTimeSeries)
%                 thisTrial = app.SavedData.TrialTimeSeries{j}';
%                 dynamicsDiff = [dynamicsDiff; filterData(thisTrial, 0, kernel, 1, 0)];
%                 dynamicsDiff(end,:) = nan(size(dynamicsDiff(1,:)));
%             end
%                
%             [bestLocalProjections, bestSigmaValues, bestDiffSigmaValues, localDistances, bestNeighborCounts, distanceRatios] = findBestAxes(app.SavedData.TimeSeries', dynamicsDiff, app.SavedData.TrialTimeSeries, previewIndex, [], nearestNeighbors, useLocalDimensions, 1);
            [bestLocalProjections, bestSigmaValues, bestDiffSigmaValues, localDistances, bestNeighborCounts, distanceRatios] = findBestAxes(app.SavedData.TimeSeries', app.SavedData.TrialTimeSeries, previewIndex, [], nearestNeighbors, useLocalDimensions, minReturnTime, 1);
            
            app.PreviewindexEditField.Value = previewIndex;
            
            if ~isnan(bestNeighborCounts)
                plotIndices = find(localDistances > exp(-1));
%                 localStream = app.SavedData.TimeSeries';
                localStream = (app.SavedData.TimeSeries .* bestLocalProjections)';
%                 localStream(app.SavedData.TrialSwitches,:) = nan;
                
                [pcaBasis, thisPlot, displayIndices] = app.plotPCA(1, localStream', app.SavedData.TrialSwitches);
                
%                 [plotBasis, ~] = pca(localStream, 'NumComponents', min(4, size(localStream,2)));
%                 
%                 thisPlot = app.figure(1);
%                 hold off;
%                 colors = lines(8);
%                 h = plot3(localStream*plotBasis(:,1),localStream*plotBasis(:,2),localStream*plotBasis(:,3));
%                 h.Color(4) = 0.2;
                hold on;
                scatter3(localStream(plotIndices,:)*pcaBasis(:,1),localStream(plotIndices,:)*pcaBasis(:,2),localStream(plotIndices,:)*pcaBasis(:,3), 32, localDistances(plotIndices))
                xlabel('DPC1');
                ylabel('DPC2');
                zlabel('DPC3');
                app.title(thisPlot, 'Local Neighborhood')
                colormap(jet(256));
            end
%                 position = localStream(datatips(1).DataIndex,:)*plotBasis;
%                 makedatatip(h, position)


%         end
%     
%         function loadDiffusionMap(app)
%             if (app.hasData("PreprocessData"))                
%                 app.ZScoreCheckbox.Value = app.SavedData.PreprocessData.ZScore;
%                 app.SmoothingEditField.Value = app.SavedData.PreprocessData.Smoothing;
%                 app.EmbeddelayEditField.Value = app.SavedData.PreprocessData.DelayTime;
%                 app.EmbedcountEditField.Value = app.SavedData.PreprocessData.DelayCount;
%             end
%             
%             if app.hasData('TimeSeries')
%                 app.plotProcessedData();
%             end
        end

        % Button pushed function: BuilddiffusionmapButton
        function BuildDiffusionMap(app, event)
            timeSeriesData = app.SavedData.TimeSeries;
            
            app.SavedData.NearestNeighbors = app.NearestneighborsEditField.Value;
            app.SavedData.UseLocalDimensions = app.LocalDimensionsCheckbox.Value;
            app.SavedData.RepopulateDensity = app.RepopulationdensityEditField.Value;
            app.SavedData.MinimumReturnTime = app.MinReturnTimeEditField.Value;
            
            nearestNeighbors = app.SavedData.NearestNeighbors;
            useLocalDimensions = app.SavedData.UseLocalDimensions;
            
            trialTimeSeries = app.SavedData.TrialTimeSeries;
            trialEnds = app.SavedData.TrialSwitches;
            
            repopulateDensity = app.SavedData.RepopulateDensity;
            minReturnTime = app.SavedData.MinimumReturnTime;
            
            buildDiffusionMap;
            
            app.SavedData.AsymmetricMap = asymmetricProbabilities;
            app.SavedData.FinalIndicies = finalIndicies;
            app.SavedData.FinalStream = finalDynamicsStream;
            app.SavedData.ValidStates = stateValidities;
            app.SavedData.DiffusionMapIndicies = diffusionMapIndicies;
            app.SavedData.StateHasNext = stateHasNext;
            
            app.checkStepValidity();
        end
    
        function plotDiffusionMap(app)
            displayMatrix = app.SavedData.AsymmetricMap;
            displayMatrix = displayMatrix ./ max(displayMatrix,[],2);
            
            thisPlot = app.figure(1);
            hold off;
            imagesc(displayMatrix);
            xlabel('Observed state (timestep)');
            ylabel('Observed state (timestep)');
            app.title(thisPlot, 'Diffusion Map')
            colormap(jet(256));
        end

        % Button pushed function: ReducemodelButton
        function ReduceModel(app, event)
            
            asymmetricProbabilities = app.SavedData.AsymmetricMap;
            finalDynamicsStream = app.SavedData.FinalStream;
            
            app.SavedData.PutativeClusterCounts = cell2mat(cellfun(@str2num,strsplit(app.PutativeclustercountsEditField.Value, ','),'uniform',0));    
            clusterCounts = app.SavedData.PutativeClusterCounts;
            
            app.SavedData.DistanceType = app.DistancetypeEditField.Value;    
            distanceType = app.SavedData.DistanceType;
            
            app.SavedData.MaxCheckTime = app.MaxchecktimeEditField.Value;
            maxCheckTime = app.SavedData.MaxCheckTime;
            
            stateHasNext = app.SavedData.StateHasNext;
            
            [pcaBasis, pcaOutputs] = pca(app.SavedData.FinalStream, 'NumComponents', 3);
            
%             shouldUseTerminalState = app.SavedData.UseTerminalState;
            
            reduceMatrix;
            
            if hadError == 0
                app.SavedData.ReducedMatrix = finalReducedMatrix;
                app.SavedData.ClusterIDs = clusterIDs;
                app.SavedData.ClusterMeansPCA = clusterMeansPCA;
                app.SavedData.ClusterMeans = clusterMeans;
                app.SavedData.CountClusters = countClusters;
                
                
                app.checkStepValidity();
            end
        end

        % Button pushed function: ProcessloopsButton
        function FindLoops(app, event)
            asymmetricProbabilities = app.SavedData.AsymmetricMap;
            finalDynamicsStream = app.SavedData.FinalStream;
            reducedMatrix = app.SavedData.ReducedMatrix;
            clusterIDs = app.SavedData.ClusterIDs;
            stateHasNext = app.SavedData.StateHasNext;
            stateValidities= app.SavedData.ValidStates;
            clusterMeansPCA = app.SavedData.ClusterMeansPCA;
            clusterMeans = app.SavedData.ClusterMeans;
            countClusters = app.SavedData.CountClusters;
            
            app.SavedData.PutativeLoopCounts = cell2mat(cellfun(@str2num,strsplit(app.PutativeloopcountsEditField.Value, ','),'uniform',0));    
            putativeLoopCounts = app.SavedData.PutativeLoopCounts;
            
            app.SavedData.UseTerminalState = app.TerminalStateCheckbox.Value;    
            shouldUseTerminalState = app.SavedData.UseTerminalState;
            
            app.SavedData.TotalStates = app.TargetstatecountEditField.Value;
            totalClusters = app.SavedData.TotalStates;
            
            selectingLoops = 0;
            
            [pcaBasis, pcaOutputs] = pca(app.SavedData.FinalStream, 'NumComponents', 3);
            
            
            trialSwitchTimes = app.SavedData.TrialSwitches;
            
            buildMinimalModelFromMatrix;
            
            app.SavedData.BestStateCount = bestStateCount;
            app.SavedData.LogLikelihoods = allLikelihoods;
            app.SavedData.BestLoopCount = bestLoopCount;
            app.SavedData.BestModel = bestModel;
            app.SavedData.BestEmission = bestEmission;
            app.SavedData.BestLoopAssignments = bestLoopAssignments;
            app.SavedData.BestStateMap = bestStateMap;
            
            app.checkStepValidity();
        end

        % Button pushed function: LoadcomparisonmodelButton
        function LoadModel(app, event)
            %set up selector UI
            app.ValidatePanel.Visible = 'off';
            app.LoadMarkovPanel.Visible = 'on';
            
            app.STATESEditField.Value = size(app.SavedData.BestModel,1);
        end

        % Button pushed function: LoadButton_2
        function LoadModelData(app, event)
            if ~evalin('base', ['exist(''' app.TransitionmatrixEditField.Value ''', ''var'')'])
                app.warning(['Variable ' app.TransitionmatrixEditField.Value ' not found in workspace.'])
            elseif ~evalin('base', ['exist(''' app.EmissionsEditField.Value ''', ''var'')'])
                app.warning(['Variable ' app.EmissionsEditField.Value ' not found in workspace.'])
            else
                app.SavedData.AltTransitions = evalin('base', app.TransitionmatrixEditField.Value);
                app.SavedData.AltEmissions = evalin('base', app.EmissionsEditField.Value);      
                
                app.ValidatePanel.Visible = 'on';
                app.LoadMarkovPanel.Visible = 'off';
                
                app.checkStepValidity();
            end
        end

        % Button pushed function: CancelButton_2
        function CancelLoadModel(app, event)
            app.ValidatePanel.Visible = 'on';
            app.LoadMarkovPanel.Visible = 'off';
        end

        % Button pushed function: ClearcomparisonmodelButton
        function ClearModel(app, event)
            app.removeData('AltTransitions');
            app.removeData('AltEmissions');
            
            app.checkStepValidity();
        end

        % Button pushed function: ValidateButton_2
        function Validate(app, event)
            finalDynamicsStream = app.SavedData.FinalStream;
            validationModel = app.SavedData.BestModel;
            validationEmission = app.SavedData.BestEmission;        
            
            if isfield(app.SavedData,'AltTransitions')
                validationModel = app.SavedData.AltTransitions;
                validationEmission = app.SavedData.AltEmissions;    
            end
            
            if isfield(app.SavedData, 'AltDynamics')
                finalDynamicsStream = app.SavedData.AltDynamics;
            end
            
            validateModel;
            
            numDataPoints = size(finalDynamicsStream, 1);
            
            disp(['Score: ' num2str(scoreMean) ' +/- ' num2str(1.96 * scoreSTD / sqrt(size(finalDynamicsStream,1)))]);
        end

        % Button pushed function: ClearvalidationdataButton
        function ClearValidationData(app, event)
            app.removeData('AltDynamics');
            app.removeData('AltRawData');
            app.removeData('AltInputs');
            app.removeData('AltOutputs');
            
            app.checkStepValidity();
        end

        % Button pushed function: LoadvalidationdataButton
        function LoadValidationData(app, event)
            %set up selector UI
            app.ValidatePanel.Visible = 'off';
            app.showLoadPanel(true);
        end

        % Button pushed function: Cancel
        function CancelLoadData(app, event)
            app.ValidatePanel.Visible = 'on';
            app.LoadDataPanel.Visible = 'off';
        end

        % Button pushed function: SelectloopsButton
        function SelectLoops(app, event)
            asymmetricProbabilities = app.SavedData.AsymmetricMap;
            finalDynamicsStream = app.SavedData.FinalStream;
            reducedMatrix = app.SavedData.ReducedMatrix;
            clusterIDs = app.SavedData.ClusterIDs;
            stateHasNext = app.SavedData.StateHasNext;
            stateValidities= app.SavedData.ValidStates;
            clusterMeansPCA = app.SavedData.ClusterMeansPCA;
            clusterMeans = app.SavedData.ClusterMeans;
            countClusters = app.SavedData.CountClusters;
            
            putativeLoopCounts = app.StartingloopsEditField.Value;
            
            app.SavedData.TotalStates = app.TargetstatecountEditField.Value;
            totalClusters = app.SavedData.TotalStates;
            
            app.SavedData.UseTerminalState = app.TerminalStateCheckbox.Value;    
            shouldUseTerminalState = app.SavedData.UseTerminalState;
            
            selectingLoops = 1;
            
            trialSwitchTimes = app.SavedData.TrialSwitches;
            
            [pcaBasis, pcaOutputs] = pca(app.SavedData.FinalStream, 'NumComponents', 3);
            
            
            buildMinimalModelFromMatrix;
            
%             app.SavedData.BestStateCount = bestStateCount;
%             app.SavedData.LogLikelihoods = allLikelihoods;
%             app.SavedData.BestLoopCount = bestLoopCount;
%             app.SavedData.BestModel = bestModel;
%             app.SavedData.BestEmission = bestEmission;
%             
%             app.checkStepValidity();
        end
    end
    
    methods (Access = public)
        function updateLoops(app, UIData)
            
            TOTAL_LOOPS = UIData.TOTAL_LOOPS;
            maxClusters = UIData.maxClusters;
            clusterIDs = UIData.clusterIDs;
            loopClusterIDs = UIData.loopClusterIDs;
            uniqueLoops = UIData.uniqueLoops;
            loopCorrelationMatrix = UIData.loopCorrelationMatrix;
            allLoops = UIData.allLoops;
            stateSimilarities = UIData.stateSimilarities;
            loopWeight = UIData.loopWeight;
            loopLengths = UIData.loopLengths;
            finalDynamicsStream = UIData.finalDynamicsStream;
            pcaBasis = UIData.pcaBasis;
            loopBandwidth = UIData.loopBandwidth;
            clusterMeans = UIData.clusterMeans;
            countClusters = UIData.countClusters;
            TOTAL_LOOPS = UIData.TOTAL_LOOPS;
            TOTAL_LOOPS = UIData.TOTAL_LOOPS;
            
            shouldUseTerminalState = app.SavedData.UseTerminalState;
            
            buildMinimalModelFromLoops
            
            app.SavedData.BestStateCount = size(minimalModel,1);
            app.SavedData.BestLoopCount = maxClusters;
            app.SavedData.BestModel = minimalModel;
            app.SavedData.BestEmission = statePositions;
            app.SavedData.BestLoopAssignments = uniqueStates;
            app.SavedData.BestStateMap = stateMap;
            
            validationModel = minimalModel;
            validationEmission = statePositions;
            
            validateModel;
            
            app.SavedData.LogLikelihoods = scoreMean;
            
            app.checkStepValidity();
            
            test = 1;
        end

        % Button pushed function: RunallButton
        function RunAllSteps(app, event)
            app.ApplyPreprocessing();
            app.BuildDiffusionMap();
            app.ReduceModel();
            app.FindLoops();
        end

        % Button pushed function: RunclusteringButton
        function RunClusteringSteps(app, event)
            app.ReduceModel();
            app.FindLoops();
        end

        % Button pushed function: SaveToWorkspaceButton
        function SaveToWorkSpace(app, event)
            app.saveLoadedData('tempLOOPERData.mat');        
            
            evalin('base', "load('tempLOOPERData.mat');")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainPanel = uipanel(app.UIFigure);
            app.SelectstepButtonGroup = uibuttongroup(app.MainPanel);
            app.SelectstepButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StepSelected, true);
            app.SelectstepButtonGroup.Title = 'Select step';
            app.LoadButton = uiradiobutton(app.SelectstepButtonGroup);
            app.LoadButton.Tooltip = {'Loads the raw, unprocessed data from the neural network'};
            app.LoadButton.Text = 'Load data';
            app.LoadButton.Value = true;
            app.PreprocessingButton = uiradiobutton(app.SelectstepButtonGroup);
            app.PreprocessingButton.Tooltip = {'Preproceses data to prepare for proper analysis via diffusion map. '};
            app.PreprocessingButton.Text = 'Preprocessing';
            app.DiffusionMapButton = uiradiobutton(app.SelectstepButtonGroup);
            app.DiffusionMapButton.Tooltip = {'This will build and display the diffusion map of the node'};
            app.DiffusionMapButton.Text = 'Diffusion map';
            app.ModelReductionButton = uiradiobutton(app.SelectstepButtonGroup);
            app.ModelReductionButton.Tooltip = {'Cleans up unhelpful/not useful parts of the diffusion map/model '};
            app.ModelReductionButton.Text = 'Model reduction';
            app.FindLoopsButton = uiradiobutton(app.SelectstepButtonGroup);
            app.FindLoopsButton.Tooltip = {'Traces through the diffusion map to find the different loops in the network (which represent behaviors) '};
            app.FindLoopsButton.Text = 'Find loops';
            app.ValidateButton = uiradiobutton(app.SelectstepButtonGroup);
            app.ValidateButton.Enable = 'off';
            app.ValidateButton.Tooltip = {'Checks the accuracy of the model against the testing data'};
            app.ValidateButton.Text = 'Validate';
            app.SavedataButton = uibutton(app.MainPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFile, true);
            app.SavedataButton.Text = 'Save data';
            app.ExperimentNameLabel = uilabel(app.MainPanel);
            app.ExperimentNameLabel.Text = 'Experiment Name:';
            app.ExperimentNameEditField = uieditfield(app.MainPanel, 'text');
            app.LoadmatfileButton = uibutton(app.MainPanel, 'push');
            app.LoadmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadmatfileButton.Tooltip = {'Load in a matlab file '};
            app.LoadmatfileButton.Text = 'Load matfile';
            app.PlottimesEditFieldLabel = uilabel(app.MainPanel);
            app.PlottimesEditFieldLabel.Text = 'Plot times';
            app.PlottimesEditField = uieditfield(app.MainPanel, 'text');
            app.RunallButton = uibutton(app.MainPanel, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllSteps, true);
            app.RunallButton.Text = 'Run all';
            app.RunclusteringButton = uibutton(app.MainPanel, 'push');
            app.RunclusteringButton.ButtonPushedFcn = createCallbackFcn(app, @RunClusteringSteps, true);
            app.RunclusteringButton.Tooltip = {'Runs the clustering algorithm to group together data into bins representing behaviors'};
            app.RunclusteringButton.Text = 'Run clustering';
            app.SaveToWorkspaceButton = uibutton(app.MainPanel, 'push');
            app.SaveToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToWorkSpace, true);
            app.SaveToWorkspaceButton.Text = 'Save To Workspace';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load data';
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel = uilabel(app.LoadDataPanel);
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel.Text = {'Load time series data into the workspace. Enter name of '; 'the variable to load, or click the search button to browse.'; 'For multiple trials use cell array of CHANNEL x TICKS, or '; 'CHANNEL x TICK x TRIAL'};
            app.DataEditFieldLabel = uilabel(app.LoadDataPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.LoadDataPanel, 'text');
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel = uilabel(app.LoadDataPanel);
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel.Text = 'INPUTS x TIME (Optional)';
            app.InputsEditFieldLabel = uilabel(app.LoadDataPanel);
            app.InputsEditFieldLabel.Text = 'Inputs';
            app.InputsEditField = uieditfield(app.LoadDataPanel, 'text');
            app.YoumayoptionallyspecifyoutputsLabel = uilabel(app.LoadDataPanel);
            app.YoumayoptionallyspecifyoutputsLabel.Text = 'OUTPUTS x TIME (Optional)';
            app.OutputsEditFieldLabel = uilabel(app.LoadDataPanel);
            app.OutputsEditFieldLabel.Text = 'Outputs';
            app.OutputsEditField = uieditfield(app.LoadDataPanel, 'text');
            app.LoadworkspacedataButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadworkspacedataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadworkspacedataButton.Text = 'Load workspace data';
            app.DataSearch = uibutton(app.LoadDataPanel, 'push');
            app.DataSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.DataSearch.Icon = 'MagGLass.png';
            app.DataSearch.Text = '';
            app.InputsSearch = uibutton(app.LoadDataPanel, 'push');
            app.InputsSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.InputsSearch.Icon = 'MagGLass.png';
            app.InputsSearch.Text = '';
            app.OutputsSearch = uibutton(app.LoadDataPanel, 'push');
            app.OutputsSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.OutputsSearch.Icon = 'MagGLass.png';
            app.OutputsSearch.Text = '';
            app.Cancel = uibutton(app.LoadDataPanel, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelLoadData, true);
            app.Cancel.Text = 'Cancel';
            app.FigureTabGroup = uitabgroup(app.UIFigure);
            app.FigureTab1 = uitab(app.FigureTabGroup);
            app.FigureTab1.Title = 'Figure 1';
            app.Figure1 = uiaxes(app.FigureTab1);
            xlabel(app.Figure1, 'X')
            ylabel(app.Figure1, 'Y')
            app.SelectDataPanel = uipanel(app.UIFigure);
            app.SelectDataPanel.Title = 'Select data';
            app.Label = uilabel(app.SelectDataPanel);
            app.Label.Text = '';
            app.SelectDataList = uilistbox(app.SelectDataPanel);
            app.SelectDataList.Items = {'Test'};
            app.SelectDataList.ValueChangedFcn = createCallbackFcn(app, @ClickData, true);
            app.SelectDataList.Value = 'Test';
            app.SelectvariableButton = uibutton(app.SelectDataPanel, 'push');
            app.SelectvariableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVariable, true);
            app.SelectvariableButton.Text = 'Select variable';
            app.CancelButton = uibutton(app.SelectDataPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCancel, true);
            app.CancelButton.Text = 'Cancel';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.ApplyprocessingButton = uibutton(app.PreprocessingPanel, 'push');
            app.ApplyprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPreprocessing, true);
            app.ApplyprocessingButton.Text = 'Apply processing';
            app.ZScoreCheckbox = uicheckbox(app.PreprocessingPanel);
            app.ZScoreCheckbox.Text = '';
            app.SmoothingEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.SmoothingEditField.Limits = [0 50];
            app.ZScoreLabel = uilabel(app.PreprocessingPanel);
            app.ZScoreLabel.Text = 'Z-Score';
            app.EmbeddelayEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.EmbeddelayEditFieldLabel.Text = 'Embed delay';
            app.EmbeddelayEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.EmbeddelayEditField.Limits = [0 10000];
            app.EmbedcountEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.EmbedcountEditFieldLabel.Text = 'Embed count';
            app.EmbedcountEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.EmbedcountEditField.Limits = [0 10000];
            app.InputlambdaEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.InputlambdaEditFieldLabel.Text = 'Input lambda';
            app.InputlambdaEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.InputlambdaEditField.Limits = [0 10];
            app.InputlambdaEditField.Value = 1;
            app.OutputlambdaEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.OutputlambdaEditFieldLabel.Text = 'Output lambda';
            app.OutputlambdaEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.OutputlambdaEditField.Limits = [0 10];
            app.OutputlambdaEditField.Value = 1;
            app.MergeStartCheckbox = uicheckbox(app.PreprocessingPanel);
            app.MergeStartCheckbox.Text = '';
            app.MergetrialstartLabel = uilabel(app.PreprocessingPanel);
            app.MergetrialstartLabel.Text = 'Merge trial start';
            app.DiffusionmappingPanel = uipanel(app.UIFigure);
            app.DiffusionmappingPanel.Title = 'Diffusion mapping';
            app.BuilddiffusionmapButton = uibutton(app.DiffusionmappingPanel, 'push');
            app.BuilddiffusionmapButton.ButtonPushedFcn = createCallbackFcn(app, @BuildDiffusionMap, true);
            app.BuilddiffusionmapButton.Text = 'Build diffusion map';
            app.PreviewneighorhoodButton = uibutton(app.DiffusionmappingPanel, 'push');
            app.PreviewneighorhoodButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewNeighborhood, true);
            app.PreviewneighorhoodButton.Text = 'Preview neighorhood';
            app.PreviewindexEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.PreviewindexEditFieldLabel.Text = 'Preview index';
            app.PreviewindexEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.PreviewindexEditField.Limits = [1 Inf];
            app.PreviewindexEditField.Value = 1;
            app.NearestneighborsEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.NearestneighborsEditFieldLabel.Text = 'Nearest neighbors';
            app.NearestneighborsEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.NearestneighborsEditField.Limits = [2 Inf];
            app.NearestneighborsEditField.Value = 6;
            app.LocalDimensionsCheckbox = uicheckbox(app.DiffusionmappingPanel);
            app.LocalDimensionsCheckbox.Text = '';
            app.UselocaldimensionsLabel = uilabel(app.DiffusionmappingPanel);
            app.UselocaldimensionsLabel.Text = 'Use local dimensions';
            app.RepopulationdensityEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.RepopulationdensityEditFieldLabel.Text = 'Repopulation density';
            app.RepopulationdensityEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.RepopulationdensityEditField.Limits = [0 1];
            app.RepopulationdensityEditField.Value = 0.95;
            app.MinReturnTimeEditFieldLabel = uilabel(app.DiffusionmappingPanel);
            app.MinReturnTimeEditFieldLabel.Text = 'Min Return Time';
            app.MinReturnTimeEditField = uieditfield(app.DiffusionmappingPanel, 'numeric');
            app.MinReturnTimeEditField.Limits = [0 100];
            app.MinReturnTimeEditField.Value = 10;
            app.ModelreductionPanel = uipanel(app.UIFigure);
            app.ModelreductionPanel.Title = 'Model reduction';
            app.ReducemodelButton = uibutton(app.ModelreductionPanel, 'push');
            app.ReducemodelButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceModel, true);
            app.ReducemodelButton.Text = 'Reduce model';
            app.PutativeclustercountsEditFieldLabel = uilabel(app.ModelreductionPanel);
            app.PutativeclustercountsEditFieldLabel.Text = 'Putative cluster counts';
            app.PutativeclustercountsEditField = uieditfield(app.ModelreductionPanel, 'text');
            app.PutativeclustercountsEditField.Value = '100, 80, 60, 50, 40, 30, 20, 17, 15, 12, 10, 8';
            app.SeparatewithcommasLabel = uilabel(app.ModelreductionPanel);
            app.SeparatewithcommasLabel.Text = '(Separate with commas)';
            app.DistancemeasureEditFieldLabel = uilabel(app.ModelreductionPanel);
            app.DistancemeasureEditFieldLabel.Text = 'Distance measure';
            app.DistancetypeEditField = uieditfield(app.ModelreductionPanel, 'text');
            app.DistancetypeEditField.Value = 'correlation';
            app.MaxchecktimeEditFieldLabel = uilabel(app.ModelreductionPanel);
            app.MaxchecktimeEditFieldLabel.Text = 'Max check time';
            app.MaxchecktimeEditField = uieditfield(app.ModelreductionPanel, 'numeric');
            app.MaxchecktimeEditField.Limits = [2 100];
            app.MaxchecktimeEditField.Value = 10;
            app.FindloopsPanel = uipanel(app.UIFigure);
            app.FindloopsPanel.Title = 'Find loops';
            app.ProcessloopsButton = uibutton(app.FindloopsPanel, 'push');
            app.ProcessloopsButton.ButtonPushedFcn = createCallbackFcn(app, @FindLoops, true);
            app.ProcessloopsButton.Text = 'Find loops';
            app.SeparatewithcommasLabel_2 = uilabel(app.FindloopsPanel);
            app.SeparatewithcommasLabel_2.Text = '(Separate with commas)';
            app.PutativeloopcountsEditFieldLabel = uilabel(app.FindloopsPanel);
            app.PutativeloopcountsEditFieldLabel.Text = 'Putative loop counts';
            app.PutativeloopcountsEditField = uieditfield(app.FindloopsPanel, 'text');
            app.PutativeloopcountsEditField.Value = '5, 4, 3, 2';
            app.TargetstatecountEditFieldLabel = uilabel(app.FindloopsPanel);
            app.TargetstatecountEditFieldLabel.Text = 'Target state count';
            app.TargetstatecountEditField = uieditfield(app.FindloopsPanel, 'numeric');
            app.TargetstatecountEditField.Limits = [1 Inf];
            app.TargetstatecountEditField.Value = 100;
            app.SelectloopsButton = uibutton(app.FindloopsPanel, 'push');
            app.SelectloopsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLoops, true);
            app.SelectloopsButton.Text = 'Select loops';
            app.StartingloopsEditFieldLabel = uilabel(app.FindloopsPanel);
            app.StartingloopsEditFieldLabel.Text = 'Starting loops';
            app.StartingloopsEditField = uieditfield(app.FindloopsPanel, 'numeric');
            app.StartingloopsEditField.Limits = [1 Inf];
            app.StartingloopsEditField.Value = 6;
            app.TerminalStateCheckbox = uicheckbox(app.FindloopsPanel);
            app.TerminalStateCheckbox.Text = '';
            app.UseterminalstateLabel = uilabel(app.FindloopsPanel);
            app.UseterminalstateLabel.Text = 'Use terminal state';
            app.LoadMarkovPanel = uipanel(app.UIFigure);
            app.LoadMarkovPanel.Title = 'Load alternate Markov model';
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel_2 = uilabel(app.LoadMarkovPanel);
            app.LoaddataintoworkspaceandenterthenameofthevariableLabel_2.Text = {'Load time series data into the workspace. Enter name of '; 'the variable to load, or click the search button to browse.'; 'STATES x STATES '};
            app.TransitionmatrixEditFieldLabel = uilabel(app.LoadMarkovPanel);
            app.TransitionmatrixEditFieldLabel.Text = 'Transition matrix';
            app.TransitionmatrixEditField = uieditfield(app.LoadMarkovPanel, 'text');
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel_2 = uilabel(app.LoadMarkovPanel);
            app.YoumayoptionallyspecifyinputstoloadINPUTSxTIMELabel_2.Text = 'STATES x CHANNELS';
            app.EmissionsEditFieldLabel = uilabel(app.LoadMarkovPanel);
            app.EmissionsEditFieldLabel.Text = 'Emissions';
            app.EmissionsEditField = uieditfield(app.LoadMarkovPanel, 'text');
            app.LoadButton_2 = uibutton(app.LoadMarkovPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadModelData, true);
            app.LoadButton_2.Text = 'Load';
            app.TransitionmatrixSearch = uibutton(app.LoadMarkovPanel, 'push');
            app.TransitionmatrixSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.TransitionmatrixSearch.Icon = 'MagGLass.png';
            app.TransitionmatrixSearch.Text = '';
            app.EmissionsSearch = uibutton(app.LoadMarkovPanel, 'push');
            app.EmissionsSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.EmissionsSearch.Icon = 'MagGLass.png';
            app.EmissionsSearch.Text = '';
            app.CancelButton_2 = uibutton(app.LoadMarkovPanel, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelLoadModel, true);
            app.CancelButton_2.Text = 'Cancel';
            app.STATESEditFieldLabel = uilabel(app.LoadMarkovPanel);
            app.STATESEditFieldLabel.Text = 'STATES';
            app.STATESEditField = uieditfield(app.LoadMarkovPanel, 'numeric');
            app.STATESEditField.Limits = [0 Inf];
            app.ValidatePanel = uipanel(app.UIFigure);
            app.ValidatePanel.Title = 'Validate';
            app.LoadcomparisonmodelButton = uibutton(app.ValidatePanel, 'push');
            app.LoadcomparisonmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModel, true);
            app.LoadcomparisonmodelButton.Tooltip = {'Load a model you''ve created/trained before'};
            app.LoadcomparisonmodelButton.Text = 'Load comparison model';
            app.ClearcomparisonmodelButton = uibutton(app.ValidatePanel, 'push');
            app.ClearcomparisonmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ClearModel, true);
            app.ClearcomparisonmodelButton.Tooltip = {'Throws away the current model'};
            app.ClearcomparisonmodelButton.Text = 'Clear comparison model';
            app.ValidateButton_2 = uibutton(app.ValidatePanel, 'push');
            app.ValidateButton_2.ButtonPushedFcn = createCallbackFcn(app, @Validate, true);
            app.ValidateButton_2.Tooltip = {'Test your model against testing data '};
            app.ValidateButton_2.Text = 'Validate';
            app.ViewspectraButton = uibutton(app.ValidatePanel, 'push');
            app.ViewspectraButton.Tooltip = {'Look at a graphic representing the loops'};
            app.ViewspectraButton.Text = 'View spectra';
            app.BestloopcountEditFieldLabel = uilabel(app.ValidatePanel);
            app.BestloopcountEditFieldLabel.Text = 'Best loop count';
            app.BestloopcountEditField = uieditfield(app.ValidatePanel, 'text');
            app.BestloopcountEditField.Editable = 'off';
            app.BeststatecountEditFieldLabel = uilabel(app.ValidatePanel);
            app.BeststatecountEditFieldLabel.Text = 'Best state count';
            app.BeststatecountEditField = uieditfield(app.ValidatePanel, 'text');
            app.BeststatecountEditField.Editable = 'off';
            app.LoadvalidationdataButton = uibutton(app.ValidatePanel, 'push');
            app.LoadvalidationdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadValidationData, true);
            app.LoadvalidationdataButton.Tooltip = {'Load your own testing data'};
            app.LoadvalidationdataButton.Text = 'Load validation data';
            app.ClearvalidationdataButton = uibutton(app.ValidatePanel, 'push');
            app.ClearvalidationdataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearValidationData, true);
            app.ClearvalidationdataButton.Tooltip = {'Clears testing data '};
            app.ClearvalidationdataButton.Text = 'Clear validation data';
            app.YoumayoptionallyspecifyoutputsLabel_2 = uilabel(app.UIFigure);
            app.YoumayoptionallyspecifyoutputsLabel_2.Text = 'TIME x TICK (Defaults to 1 per tick)';
            app.TimeSearch = uibutton(app.UIFigure, 'push');
            app.TimeSearch.ButtonPushedFcn = createCallbackFcn(app, @SearchWorkspace, true);
            app.TimeSearch.Icon = 'MagGLass.png';
            app.TimeSearch.Text = '';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.ChaaruCheckbox = uicheckbox(app.UIFigure);
            app.ChaaruCheckbox.Enable = 'off';
            app.ChaaruCheckbox.Text = '';
            app.LocaldimensionsLabel = uilabel(app.UIFigure);
            app.LocaldimensionsLabel.Text = 'Local dimensions';
            app.SlowLabel = uilabel(app.UIFigure);
            app.SlowLabel.Text = '(Slow)';
            app.MergeEndCheckbox = uicheckbox(app.UIFigure);
            app.MergeEndCheckbox.Text = '';
            app.MergetrialendsLabel = uilabel(app.UIFigure);
            app.MergetrialendsLabel.Text = 'Merge trial ends';
            app.TriallambdaEditFieldLabel = uilabel(app.UIFigure);
            app.TriallambdaEditFieldLabel.Text = 'Trial lambda';
            app.TriallambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TriallambdaEditField.Limits = [0 10];
            app.TriallambdaEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOOPER(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end